classdef ExportOptionsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        GeneralOptionsPanel         matlab.ui.container.Panel
        IncludeWavenumbersCheckBox  matlab.ui.control.CheckBox
        ExportFormatDropDown        matlab.ui.control.DropDown
        ExportFormatDropDownLabel   matlab.ui.control.Label
        DirectionButtonGroup        matlab.ui.container.ButtonGroup
        VerticalButton              matlab.ui.control.RadioButton
        HorizontalButton            matlab.ui.control.RadioButton
        RandomSelectionPanel        matlab.ui.container.Panel
        NumSpectraEditField         matlab.ui.control.NumericEditField
        NoofSpectraEditFieldLabel   matlab.ui.control.Label
        IncludeSelectedSpectralIndexCheckBox  matlab.ui.control.CheckBox
        OnlyExportRandomlySelectedSubsetCheckBox  matlab.ui.control.CheckBox
        GridLayout2                 matlab.ui.container.GridLayout
        ExportButton                matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        data; % Handles to data to be exported
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function export_start(app, data)
            arguments
                app ExportOptionsDialog;
                data = [];
            end

            if isempty(data), return; end

            % Load data
            app.data = data;

            % Get export options
            app.ExportFormatDropDown.Items = data.get_export_formats();
        end

        % Value changed function: OnlyExportRandomlySelectedSubsetCheckBox
        function OnlyExportRandomlySelectedSubsetCheckBoxValueChanged(app, event)
            % Make Rest Visible
            value = app.OnlyExportRandomlySelectedSubsetCheckBox.Value;
            
            app.IncludeSelectedSpectralIndexCheckBox.Enable = value;
            app.NumSpectraEditField.Enable = value;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Uses state of gui app to pass arguments

            arguments
                app ExportOptionsDialog;
                event;
            end

            % Check if data is present
            if isempty(app.data), return; end

            % Retrieve gui export options
            format = app.ExportFormatDropDown.Value;
            include_wavenum = app.IncludeWavenumbersCheckBox.Value;
            if app.VerticalButton.Value, direction = "vertical"; end
            if app.HorizontalButton.Value, direction = "horizontal"; end
            random_subset = app.OnlyExportRandomlySelectedSubsetCheckBox.Value;
            include_indices = app.IncludeSelectedSpectralIndexCheckBox.Value;
            rand_num = app.NumSpectraEditField.Value;

            % Call export method
            app.data.export( ...
                format=format, ...
                direction=direction, ...
                include_wavenum=include_wavenum, ...
                rand_subset=random_subset, ...
                rand_num=rand_num);

            % Close app
            app.delete();

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ExportButton = uibutton(app.GridLayout2, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RandomSelectionPanel = uipanel(app.GridLayout3);
            app.RandomSelectionPanel.Title = 'Random Selection';
            app.OnlyExportRandomlySelectedSubsetCheckBox = uicheckbox(app.RandomSelectionPanel);
            app.OnlyExportRandomlySelectedSubsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlyExportRandomlySelectedSubsetCheckBoxValueChanged, true);
            app.OnlyExportRandomlySelectedSubsetCheckBox.Text = 'Only Export Randomly Selected Subset';
            app.IncludeSelectedSpectralIndexCheckBox = uicheckbox(app.RandomSelectionPanel);
            app.IncludeSelectedSpectralIndexCheckBox.Enable = 'off';
            app.IncludeSelectedSpectralIndexCheckBox.Text = 'Include Selected Spectral Index';
            app.IncludeSelectedSpectralIndexCheckBox.Value = true;
            app.NoofSpectraEditFieldLabel = uilabel(app.RandomSelectionPanel);
            app.NoofSpectraEditFieldLabel.Text = 'No. of Spectra';
            app.NumSpectraEditField = uieditfield(app.RandomSelectionPanel, 'numeric');
            app.NumSpectraEditField.Limits = [0 Inf];
            app.NumSpectraEditField.RoundFractionalValues = 'on';
            app.NumSpectraEditField.ValueDisplayFormat = '%.0f';
            app.NumSpectraEditField.Value = 100;
            app.DirectionButtonGroup = uibuttongroup(app.GridLayout3);
            app.DirectionButtonGroup.Title = 'Direction';
            app.HorizontalButton = uiradiobutton(app.DirectionButtonGroup);
            app.HorizontalButton.Text = 'Horizontal';
            app.HorizontalButton.Value = true;
            app.VerticalButton = uiradiobutton(app.DirectionButtonGroup);
            app.VerticalButton.Text = 'Vertical';
            app.GeneralOptionsPanel = uipanel(app.GridLayout3);
            app.GeneralOptionsPanel.Title = 'General Options';
            app.ExportFormatDropDownLabel = uilabel(app.GeneralOptionsPanel);
            app.ExportFormatDropDownLabel.Text = 'Export Format';
            app.ExportFormatDropDown = uidropdown(app.GeneralOptionsPanel);
            app.IncludeWavenumbersCheckBox = uicheckbox(app.GeneralOptionsPanel);
            app.IncludeWavenumbersCheckBox.Text = 'Include Wavenumbers';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportOptionsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)export_start(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end