classdef FilterManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ManageFiltersUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        CloseButton            matlab.ui.control.Button
        RemoveButton           matlab.ui.control.Button
        AddButton              matlab.ui.control.Button
        UIFilterTable          matlab.ui.control.Table
    end

    
    properties (Access = public)
        MainApp;    % Caller App
        DataCon;    % DataContainer
        SpectralFilterOutputViewer % Description
    end
    
    methods (Access = public)
        
        function update(app)
            filter = app.DataCon.Data.filter;
            
            tbl = table( ...
                filter.name, ...
                {filter.operation}, ...
                filter.range(1), ...
                filter.range(2));
            tbl.Properties.VariableNames = {'Name', 'Operation', 'RangeStart', 'RangeEnd'};
            
            app.UIFilterTable.Data = tbl;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datacon, options)
            arguments
                app;
                datacon;
                options.OutputViewer = SpecAreaDataViewer.empty();
            end
            
            % Link data container
            app.DataCon = datacon;
            app.SpectralFilterOutputViewer = options.OutputViewer;
            
            % Set Window Title
            app.ManageFiltersUIFigure.Name = app.DataCon.display_name;
            
            app.update();
        end

        % Display data changed function: UIFilterTable
        function UIFilterTableDisplayDataChanged(app, event)
            newDisplayData = app.UIFilterTable.DisplayData;
            
            % Set new values
            filter = app.DataCon.Data.filter;
            filter.range(1) = newDisplayData.RangeStart;
            filter.range(2) = newDisplayData.RangeEnd;
            
            % Update Preview
            viewer = app.SpectralFilterOutputViewer;
            if ~isempty(viewer) && isvalid(viewer)
                viewer.update();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ManageFiltersUIFigure = uifigure('Visible', 'off');
            app.ManageFiltersUIFigure.Name = 'Manage Filters';
            app.GridLayout = uigridlayout(app.ManageFiltersUIFigure);
            app.UIFilterTable = uitable(app.GridLayout);
            app.UIFilterTable.ColumnName = {'Name'; 'Operation'; 'Start'; 'End'};
            app.UIFilterTable.RowName = {};
            app.UIFilterTable.ColumnEditable = [false false true true];
            app.UIFilterTable.DisplayDataChangedFcn = createCallbackFcn(app, @UIFilterTableDisplayDataChanged, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.GridLayout2, 'push');
            app.RemoveButton.Text = 'Remove';
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ManageFiltersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ManageFiltersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ManageFiltersUIFigure)
        end
    end
end