classdef ImportDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportDataUIFigure           matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        StatusTextArea               matlab.ui.control.TextArea
        StatusTextAreaLabel          matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        WITecTab                     matlab.ui.container.Tab
        ImportTextNotesCheckBox      matlab.ui.control.CheckBox
        ImportdatadirectlyfromWITecsoftwareinthewipformatLabel  matlab.ui.control.Label
        SelectWipFilesButton         matlab.ui.control.Button
        MATLABExportTab              matlab.ui.container.Tab
        SelectMatFilesButton         matlab.ui.control.Button
        NoFolderSelectedLabel        matlab.ui.control.Label
        SelectFolderButton           matlab.ui.control.Button
        StartImportButton            matlab.ui.control.Button
        ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_2  matlab.ui.control.Label
        ASCIITab                     matlab.ui.container.Tab
        ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_3  matlab.ui.control.Label
        MicrosoftExcelTab            matlab.ui.container.Tab
        CombinationPanel             matlab.ui.container.Panel
        YSizeEditField               matlab.ui.control.NumericEditField
        YSizeEditFieldLabel          matlab.ui.control.Label
        XSizeEditField               matlab.ui.control.NumericEditField
        XSizeEditFieldLabel          matlab.ui.control.Label
        CombinescansspectraCheckBox  matlab.ui.control.CheckBox
        ColumnsPanel                 matlab.ui.container.Panel
        SpectrumSpinner              matlab.ui.control.Spinner
        SpectrumSpinnerLabel         matlab.ui.control.Label
        Data1stSpinner               matlab.ui.control.Spinner
        Data1stSpinnerLabel          matlab.ui.control.Label
        SampleSpinner                matlab.ui.control.Spinner
        SampleSpinnerLabel           matlab.ui.control.Label
        GroupSpinner                 matlab.ui.control.Spinner
        GroupSpinnerLabel            matlab.ui.control.Label
        GroupingPanel                matlab.ui.container.Panel
        GroupbygroupcolumnCheckBox   matlab.ui.control.CheckBox
        GroupbysamplecolumnCheckBox  matlab.ui.control.CheckBox
        SelectXLSFilesButton         matlab.ui.control.Button
        ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_4  matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        ProcessingOptionsPanel       matlab.ui.container.Panel
        KeepOriginalCheckBox         matlab.ui.control.CheckBox
        ConverttoRelativeWavenumbersCheckBox  matlab.ui.control.CheckBox
        BaselineCorrectionCheckBox   matlab.ui.control.CheckBox
        CosmicRayRemovalCheckBox     matlab.ui.control.CheckBox
        NormalizeCheckBox            matlab.ui.control.CheckBox
        AppendtocurrentlyloadeddatasetCheckBox  matlab.ui.control.CheckBox
        GridLayout5                  matlab.ui.container.GridLayout
        CancelButton                 matlab.ui.control.Button
        ImportConfirmButton          matlab.ui.control.Button
        DataPreviewLabel             matlab.ui.control.Label
        UIPreviewTable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % Main App
        ImportedData
        SelectedFolder
        tmp_prj Project = Project.empty; % Temporary Project() Object containing data
        tmp_widata; % Temporary WIT-IO Data for conversion
        convert_data_before_import logical = false;
        import_as_prj logical = false;
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.SelectedFolder = 0;
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % Start in working directory
            start_path = pwd;
            thisFolder = uigetdir(start_path);
            
            if thisFolder == 0
                return;
            else
                app.SelectedFolder = thisFolder;
                app.StartImportButton.Enable = true;
                app.NoFolderSelectedLabel.Text = thisFolder;
            end
        end

        % Button pushed function: ImportConfirmButton
        function ImportConfirmButtonPushed(app, event)
            %IMPORTCONFIRMBUTTONPUSHED

            if app.convert_data_before_import
                % First convert the widata to RaMAT data
                if isempty(app.tmp_widata), return; end

                % Take selection only
                selection = app.UIPreviewTable.Data.selection;
                app.tmp_widata = app.tmp_widata(selection,:);

                % Get options
                opts_struct = get_processing_options(app);

                % Convert
                app.ImportedData = convert_widata(app.tmp_widata, processing=opts_struct, gui=app.StatusTextArea);
            end
                
            % Call method in main app to load in the imported data
            % app.ImportedData:     array of DataContainer instances
            if app.import_as_prj
                app.CallingApp.loadImportedData(app.tmp_prj);
                delete(app);
                return;
            end
            
            loadImportedData(app.CallingApp, app.ImportedData);
            
            % Close the import dialog
            delete(app);
        end

        % Button pushed function: StartImportButton
        function StartImportButtonPushed(app, event)
            if (app.SelectedFolder ~= 0)
                app.ImportConfirmButton.Text = "Processing ...";
                
                % Start import
                data = importRaman(app.SelectedFolder, app);
                
                % Data has been imported
                % Create preview                
                previewTable = table(...
                    {true(numel(dat),1)},...
                    {data.display_name}', ...
                    {data.dataType}');
                app.UIPreviewTable.Data = previewTable;
                
                app.ImportedData = data;
                                
                app.ImportConfirmButton.Text = "Confirm";
                app.ImportConfirmButton.Enable = true;
                
            end
        end

        % Button pushed function: SelectWipFilesButton
        function SelectWipFilesButtonPushed(app, event)
            % SELECTWIPFILESBUTTONPUSHED Show file selection dialog for WIP
            % files
            
            % TO-DO: select last used folder
            data = import_data([], type="wip", start_path=pwd, gui=app.StatusTextArea, convert_widata=false);

            if isempty(data)
                return;
            end

            select_text = app.ImportTextNotesCheckBox.Value;

            % Preview Data
            app.tmp_widata = wip_preview_table(data, preview_vars=["Name", "Type"], selection_column=true, select_text=select_text, gui_table=app.UIPreviewTable, output_to_gui=true);

            % Store temporary uncoverted WITec data
            app.convert_data_before_import = true;

            app.ImportConfirmButton.Text = "Confirm";
            app.ImportConfirmButton.Enable = true;                           
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SelectXLSFilesButton
        function SelectXLSFilesButtonPushed(app, event)
            
            opts.group_by_group = app.GroupbygroupcolumnCheckBox.Value;
            opts.group_by_sample = app.GroupbysamplecolumnCheckBox.Value;
            opts.spec_col = app.SpectrumSpinner.Value;
            opts.group_col = app.GroupSpinner.Value;
            opts.sample_col = app.SampleSpinner.Value;
            opts.data_col = app.Data1stSpinner.Value;
            opts.combine_spectra = app.CombinescansspectraCheckBox.Value;
            opts.dimensions = [app.XSizeEditField.Value, app.YSizeEditField.Value];

            opts.type = "xls";
            opts.start_path = pwd;
            opts.gui=app.StatusTextArea;

            options = unpack(opts);

            [data, groups] = import_data([], options{:});
            app.tmp_prj = Project;
            app.tmp_prj.append_groups(groups);
            app.tmp_prj.append_data(data, auto_group=false);

            if isempty(data)
                return;
            end

            app.ImportConfirmButton.Text = "Confirm";
            app.ImportConfirmButton.Enable = true;
            app.import_as_prj = true;
        end

        % Value changed function: GroupbygroupcolumnCheckBox
        function GroupbygroupcolumnCheckBoxValueChanged(app, event)
            value = app.GroupbygroupcolumnCheckBox.Value;
            
            app.GroupbysamplecolumnCheckBox.Enable = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportDataUIFigure = uifigure('Visible', 'off');
            app.ImportDataUIFigure.Name = 'Import Data';
            app.GridLayout = uigridlayout(app.ImportDataUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIPreviewTable = uitable(app.GridLayout2);
            app.UIPreviewTable.ColumnName = {'Import'; 'Name'; 'Type'};
            app.UIPreviewTable.RowName = {};
            app.UIPreviewTable.ColumnEditable = [true true false];
            app.DataPreviewLabel = uilabel(app.GridLayout2);
            app.DataPreviewLabel.Text = 'Data Preview';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ImportConfirmButton = uibutton(app.GridLayout5, 'push');
            app.ImportConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ImportConfirmButtonPushed, true);
            app.ImportConfirmButton.Enable = 'off';
            app.ImportConfirmButton.Text = 'Confirm';
            app.CancelButton = uibutton(app.GridLayout5, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AppendtocurrentlyloadeddatasetCheckBox = uicheckbox(app.GridLayout4);
            app.AppendtocurrentlyloadeddatasetCheckBox.Text = 'Append to currently loaded data set';
            app.AppendtocurrentlyloadeddatasetCheckBox.Value = true;
            app.ProcessingOptionsPanel = uipanel(app.GridLayout4);
            app.ProcessingOptionsPanel.Title = 'Processing Options';
            app.NormalizeCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.CosmicRayRemovalCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.CosmicRayRemovalCheckBox.Enable = 'off';
            app.CosmicRayRemovalCheckBox.Text = 'Cosmic Ray Removal';
            app.BaselineCorrectionCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.ConverttoRelativeWavenumbersCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.ConverttoRelativeWavenumbersCheckBox.Text = 'Convert to Relative Wavenumbers in cm-1 (Raman Shift)';
            app.KeepOriginalCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.KeepOriginalCheckBox.Tooltip = {'Keeps a copy of the original spectral data. Unchecking this option will perform spectral corrections and overwrite the spectral data.'};
            app.KeepOriginalCheckBox.Text = 'Keep Original';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WITecTab = uitab(app.TabGroup);
            app.WITecTab.Title = 'WITec';
            app.SelectWipFilesButton = uibutton(app.WITecTab, 'push');
            app.SelectWipFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWipFilesButtonPushed, true);
            app.SelectWipFilesButton.Text = 'Select Files';
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel = uilabel(app.WITecTab);
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel.Text = {'Import data directly from WITec software (ProjectFOUR, ProjectFIVE, ...)'; 'in the .wip format.'};
            app.ImportTextNotesCheckBox = uicheckbox(app.WITecTab);
            app.ImportTextNotesCheckBox.Text = 'Import Text Notes';
            app.ImportTextNotesCheckBox.Value = true;
            app.MATLABExportTab = uitab(app.TabGroup);
            app.MATLABExportTab.Title = 'MATLAB Export';
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_2 = uilabel(app.MATLABExportTab);
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_2.Text = {'Import data exported by the WITec ProjectFIVE software'; 'in MATLAB format.'};
            app.StartImportButton = uibutton(app.MATLABExportTab, 'push');
            app.StartImportButton.ButtonPushedFcn = createCallbackFcn(app, @StartImportButtonPushed, true);
            app.StartImportButton.Enable = 'off';
            app.StartImportButton.Text = 'Start Import';
            app.SelectFolderButton = uibutton(app.MATLABExportTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.NoFolderSelectedLabel = uilabel(app.MATLABExportTab);
            app.NoFolderSelectedLabel.Text = 'No Folder Selected...';
            app.SelectMatFilesButton = uibutton(app.MATLABExportTab, 'push');
            app.SelectMatFilesButton.Text = 'Select Files';
            app.ASCIITab = uitab(app.TabGroup);
            app.ASCIITab.Title = 'ASCII';
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_3 = uilabel(app.ASCIITab);
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_3.Text = 'Import ASCII data';
            app.MicrosoftExcelTab = uitab(app.TabGroup);
            app.MicrosoftExcelTab.Title = 'Microsoft Excel';
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_4 = uilabel(app.MicrosoftExcelTab);
            app.ImportdatadirectlyfromWITecsoftwareinthewipformatLabel_4.Text = 'Import data from a Microsoft Excel spreadsheet.';
            app.SelectXLSFilesButton = uibutton(app.MicrosoftExcelTab, 'push');
            app.SelectXLSFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectXLSFilesButtonPushed, true);
            app.SelectXLSFilesButton.Text = 'Select Files';
            app.GroupingPanel = uipanel(app.MicrosoftExcelTab);
            app.GroupingPanel.Title = 'Grouping';
            app.GroupbysamplecolumnCheckBox = uicheckbox(app.GroupingPanel);
            app.GroupbysamplecolumnCheckBox.Enable = 'off';
            app.GroupbysamplecolumnCheckBox.Text = 'Group by sample column';
            app.GroupbygroupcolumnCheckBox = uicheckbox(app.GroupingPanel);
            app.GroupbygroupcolumnCheckBox.ValueChangedFcn = createCallbackFcn(app, @GroupbygroupcolumnCheckBoxValueChanged, true);
            app.GroupbygroupcolumnCheckBox.Tooltip = {'Keeps a copy of the original spectral data. Unchecking this option will perform spectral corrections and overwrite the spectral data.'};
            app.GroupbygroupcolumnCheckBox.Text = 'Group by group column';
            app.ColumnsPanel = uipanel(app.MicrosoftExcelTab);
            app.ColumnsPanel.Title = 'Columns';
            app.GroupSpinnerLabel = uilabel(app.ColumnsPanel);
            app.GroupSpinnerLabel.Text = 'Group';
            app.GroupSpinner = uispinner(app.ColumnsPanel);
            app.GroupSpinner.Value = 2;
            app.SampleSpinnerLabel = uilabel(app.ColumnsPanel);
            app.SampleSpinnerLabel.Text = 'Sample';
            app.SampleSpinner = uispinner(app.ColumnsPanel);
            app.SampleSpinner.Value = 3;
            app.Data1stSpinnerLabel = uilabel(app.ColumnsPanel);
            app.Data1stSpinnerLabel.Text = 'Data (1st)';
            app.Data1stSpinner = uispinner(app.ColumnsPanel);
            app.Data1stSpinner.Value = 4;
            app.SpectrumSpinnerLabel = uilabel(app.ColumnsPanel);
            app.SpectrumSpinnerLabel.Text = 'Spectrum';
            app.SpectrumSpinner = uispinner(app.ColumnsPanel);
            app.SpectrumSpinner.Value = 1;
            app.CombinationPanel = uipanel(app.MicrosoftExcelTab);
            app.CombinationPanel.Title = 'Combination';
            app.CombinescansspectraCheckBox = uicheckbox(app.CombinationPanel);
            app.CombinescansspectraCheckBox.Tooltip = {'Keeps a copy of the original spectral data. Unchecking this option will perform spectral corrections and overwrite the spectral data.'};
            app.CombinescansspectraCheckBox.Text = 'Combine scans / spectra';
            app.CombinescansspectraCheckBox.Value = true;
            app.XSizeEditFieldLabel = uilabel(app.CombinationPanel);
            app.XSizeEditFieldLabel.Text = 'X Size:';
            app.XSizeEditField = uieditfield(app.CombinationPanel, 'numeric');
            app.YSizeEditFieldLabel = uilabel(app.CombinationPanel);
            app.YSizeEditFieldLabel.Text = 'Y Size:';
            app.YSizeEditField = uieditfield(app.CombinationPanel, 'numeric');
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.StatusTextAreaLabel = uilabel(app.GridLayout3);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.GridLayout3);
            % Show the figure after all components are created
            app.ImportDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportDataUIFigure)
        end
    end
end