classdef SpecAreaDataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectralFilterOutputUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout2                   matlab.ui.container.GridLayout
        CursorSizeLabel               matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        ButtonMinusCursor             matlab.ui.control.Button
        ButtonPlusCursor              matlab.ui.control.Button
        ManageFiltersButton           matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainApp; % Calling App
        DataCon;      % DataContainer
    end
    
    methods (Access = public)
        
        function update(app)
            %UPDATE
            
            updateSpecAreaFilterOutput(app, app.DataCon);

            update_spec_area_filter_overlay(app.UIAxes, app.DataCon.Data);
        end
    end
    
    methods (Access = public)
        
        function ui_axes = get_main_axes(app)
            %GET_MAIN_AXES Get preview axes of main app

            ui_axes = [];

            if isempty(app.MainApp)
                return;
            end

            ui_axes = app.MainApp.UIPreviewAxes;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, maindc)
            %   mainapp: Calling App
            %   maindc:  DataContainer
            
            app.MainApp = mainapp;
            app.DataCon = maindc;
            
            app.update();
        end

        % Button pushed function: ManageFiltersButton
        function ManageFiltersButtonPushed(app, event)
            % Open Filter Manager
            
            fmgr = FilterManager(app.DataCon, OutputViewer=app);
        end

        % Button pushed function: ButtonPlusCursor
        function ButtonPlusCursorPushed(app, event)
            if isempty(app.DataCon)
                return;
            end
            
            app.DataCon.Data.cursor.increase();
            update_spec_area_filter_overlay(app.UIAxes, app.DataCon.Data);
            update_spectral_preview(app.DataCon, app.get_main_axes());
        end

        % Button pushed function: ButtonMinusCursor
        function ButtonMinusCursorPushed(app, event)
            if isempty(app.DataCon)
                return;
            end
            
            app.DataCon.Data.cursor.decrease();
            update_spec_area_filter_overlay(app.UIAxes, app.DataCon.Data);
            update_spectral_preview(app.DataCon, app.get_main_axes());
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectralFilterOutputUIFigure = uifigure('Visible', 'off');
            app.SpectralFilterOutputUIFigure.Name = 'Spectral Filter Output';
            app.GridLayout = uigridlayout(app.SpectralFilterOutputUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ManageFiltersButton = uibutton(app.GridLayout2, 'push');
            app.ManageFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ManageFiltersButtonPushed, true);
            app.ManageFiltersButton.Icon = 'filter_16.png';
            app.ManageFiltersButton.Text = 'Manage Filters';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ButtonPlusCursor = uibutton(app.GridLayout3, 'push');
            app.ButtonPlusCursor.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusCursorPushed, true);
            app.ButtonPlusCursor.Icon = 'plus-12.png';
            app.ButtonPlusCursor.Text = '';
            app.ButtonMinusCursor = uibutton(app.GridLayout3, 'push');
            app.ButtonMinusCursor.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusCursorPushed, true);
            app.ButtonMinusCursor.Icon = 'minus-12.png';
            app.ButtonMinusCursor.Text = '';
            app.CursorSizeLabel = uilabel(app.GridLayout2);
            app.CursorSizeLabel.Text = 'Cursor Size:';
            % Show the figure after all components are created
            app.SpectralFilterOutputUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecAreaDataViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectralFilterOutputUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectralFilterOutputUIFigure)
        end
    end
end