classdef SpectralPlotEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        TreePanel               matlab.ui.container.Panel
        ButtonDown              matlab.ui.control.Button
        ButtonUp                matlab.ui.control.Button
        PlotTree                matlab.ui.container.CheckBoxTree
        GridLayout3             matlab.ui.container.GridLayout
        ExportasButton          matlab.ui.control.Button
        SavePlotButton          matlab.ui.control.Button
        GeneratePlotButton      matlab.ui.control.Button
        AutoUpdateCheckBox      matlab.ui.control.CheckBox
        UpdatePreviewButton     matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        Panel                   matlab.ui.container.Panel
        StackingPanel           matlab.ui.container.Panel
        StackingDistanceSlider  matlab.ui.control.Slider
        DistanceSliderLabel     matlab.ui.control.Label
        StackedPlotCheckBox     matlab.ui.control.CheckBox
        PlotOptionsPanel        matlab.ui.container.Panel
        TitleEditField          matlab.ui.control.EditField
        TitleEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        specplot; % SpecPlot
    end
    
    methods (Access = public)
        
        function generate_plot(app, preview)
            %GENERATE_PLOT Update spectral plot preview

            arguments
                app;
                preview = true;
            end
            
            if isempty(app.specplot)
                fprintf('No Data');
                return
            end
            
            plt = app.specplot;

            % When preview, target = UIAxes
            if preview
                target = app.UIAxes;
            else
                target = []; % empty target = create new figure
            end
            
            % Call plot method, target = UIAxes
            plt.plot(Axes=target);
            
        end
        
        function getplotoptions(app)
            %GETPLOTOPTIONS Gets PlotOptions und updates GUI components
            
            % Set StackDistance slider
            app.StackingDistanceSlider.Value = app.specplot.options.plot_stack_distance;
            
            % Set PlotType checkbox
            switch app.specplot.options.plot_type
                case "Stacked"
                    app.StackedPlotCheckBox.Value = true;
                case "Overlaid"
                    app.StackedPlotCheckBox.Value = false;
                otherwise
                    fprintf('Illegal PlotType');
            end
            
%             % Set Name edit field
%             app.TitleEditField.Value = app.specplot.Name;

            
        end
        
        function autoupdate(app)
            %AUTOUPDATE Wrapper for generate_plot(), respecting
            %AutoUpdate property
            
            if app.AutoUpdateCheckBox.Value
                generate_plot(app, true);
            end
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, kwargs)
            arguments
                app SpectralPlotEditor;
                data SpecPlot = SpecPlot.empty();
                kwargs.dummy = [];
            end
            
            if isempty(data)
                fprintf('Loaded empty data.\n');
            end
                                    
            % Store SpecPlot
            app.specplot = data;

            % Update Tree
            update_plot_tree(app.PlotTree, app.specplot, app);
            
            % Update GUI components
            getplotoptions(app);
            
            % Update Plot preview
            generate_plot(app);
            
        end

        % Button pushed function: UpdatePreviewButton
        function UpdatePreviewButtonPushed(app, event)
            %UPDATEPREVIEWBUTTONPUSHED Callback
            
            generate_plot(app, true);
            
        end

        % Value changed function: StackedPlotCheckBox
        function StackedPlotCheckBoxValueChanged(app, event)
            value = app.StackedPlotCheckBox.Value;
            
            if value == true
                app.specplot.options.plot_type= "Stacked";
            else
                app.specplot.options.plot_type = "Overlaid";
            end
            
            autoupdate(app);
            
        end

        % Value changed function: StackingDistanceSlider
        function StackingDistanceSliderValueChanged(app, event)
            value = app.StackingDistanceSlider.Value;
            
            app.specplot.options.plot_stack_distance = value;
            
            autoupdate(app);
            
            
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            
            app.specplot.name = value;
            
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            app.generate_plot(false);
        end

        % Button pushed function: ButtonUp
        function ButtonUpPushed(app, event)
            selection = app.PlotTree.SelectedNodes;
            if isempty(selection), return; end
            
            selection.NodeData.moveup();
            update_node(selection, "moveup");

            app.autoupdate();
        end

        % Button pushed function: ButtonDown
        function ButtonDownPushed(app, event)
            selection = app.PlotTree.SelectedNodes;
            if isempty(selection), return; end
            
            selection.NodeData.movedown();
            update_node(selection, "movedown");

            app.autoupdate();
        end

        % Node text changed function: PlotTree
        function PlotTreeNodeTextChanged(app, event)
            node = event.Node;
            if isempty(node.NodeData), return; end

            % Change name
            link = node.NodeData;
            link.name = event.Text;

            % Update plot
            app.autoupdate();
            
        end

        % Callback function: PlotTree
        function PlotTreeCheckedNodesChanged(app, event)
            app.specplot.set_selection(app.PlotTree.CheckedNodes);
            app.autoupdate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.PlotOptionsPanel = uipanel(app.Panel);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.TitleEditFieldLabel = uilabel(app.PlotOptionsPanel);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.PlotOptionsPanel, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.StackingPanel = uipanel(app.Panel);
            app.StackingPanel.Title = 'Stacking';
            app.StackedPlotCheckBox = uicheckbox(app.StackingPanel);
            app.StackedPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @StackedPlotCheckBoxValueChanged, true);
            app.StackedPlotCheckBox.Text = 'Stacked Plot';
            app.DistanceSliderLabel = uilabel(app.StackingPanel);
            app.DistanceSliderLabel.Text = 'Distance';
            app.StackingDistanceSlider = uislider(app.StackingPanel);
            app.StackingDistanceSlider.Limits = [0 2];
            app.StackingDistanceSlider.MajorTicks = [0 1 2];
            app.StackingDistanceSlider.MajorTickLabels = {'min', 'default', 'max'};
            app.StackingDistanceSlider.ValueChangedFcn = createCallbackFcn(app, @StackingDistanceSliderValueChanged, true);
            app.StackingDistanceSlider.MinorTicks = [];
            app.StackingDistanceSlider.Value = 1;
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UpdatePreviewButton = uibutton(app.GridLayout3, 'push');
            app.UpdatePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.AutoUpdateCheckBox = uicheckbox(app.GridLayout3);
            app.AutoUpdateCheckBox.Text = 'Auto Update';
            app.AutoUpdateCheckBox.Value = true;
            app.GeneratePlotButton = uibutton(app.GridLayout3, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = 'Generate Plot';
            app.SavePlotButton = uibutton(app.GridLayout3, 'push');
            app.SavePlotButton.Text = 'Save Plot';
            app.ExportasButton = uibutton(app.GridLayout3, 'push');
            app.ExportasButton.Text = 'Export as ...';
            app.PlotTree = uitree(app.GridLayout2, 'checkbox');
            app.PlotTree.NodeTextChangedFcn = createCallbackFcn(app, @PlotTreeNodeTextChanged, true);
            app.PlotTree.Editable = 'on';
            % Assign Checked Nodes
            app.PlotTree.CheckedNodesChangedFcn = createCallbackFcn(app, @PlotTreeCheckedNodesChanged, true);
            app.TreePanel = uipanel(app.GridLayout2);
            app.ButtonUp = uibutton(app.TreePanel, 'push');
            app.ButtonUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpPushed, true);
            app.ButtonUp.Text = '▲';
            app.ButtonDown = uibutton(app.TreePanel, 'push');
            app.ButtonDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonDownPushed, true);
            app.ButtonDown.Text = '▼';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpectralPlotEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end