classdef TrimToDlg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrimtoUIFigure       matlab.ui.Figure
        CancelButton         matlab.ui.control.Button
        TrimSelectedButton   matlab.ui.control.Button
        Label                matlab.ui.control.Label
        AsCopyCheckBox       matlab.ui.control.CheckBox
        RangeEndField        matlab.ui.control.NumericEditField
        cm1EditFieldLabel    matlab.ui.control.Label
        RangeStartField      matlab.ui.control.NumericEditField
        RangeEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, options)
            arguments
                app
                mainApp
                options.copy logical = false;
            end
            
            app.CallingApp = mainApp;

            app.AsCopyCheckBox.Value = options.copy;
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: TrimSelectedButton
        function TrimSelectedButtonPushed(app, event)
            startX = app.RangeStartField.Value;
            endX = app.RangeEndField.Value;
            
            as_copy = app.AsCopyCheckBox.Value;
            range = [startX endX];
        
            app.CallingApp.trim_spectra(range, copy=as_copy);
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrimtoUIFigure = uifigure('Visible', 'off');
            app.TrimtoUIFigure.Name = 'Trim to ...';
            app.RangeEditFieldLabel = uilabel(app.TrimtoUIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeStartField = uieditfield(app.TrimtoUIFigure, 'numeric');
            app.cm1EditFieldLabel = uilabel(app.TrimtoUIFigure);
            app.cm1EditFieldLabel.Text = 'cm-1';
            app.RangeEndField = uieditfield(app.TrimtoUIFigure, 'numeric');
            app.AsCopyCheckBox = uicheckbox(app.TrimtoUIFigure);
            app.AsCopyCheckBox.Text = 'As Copy (don''t overwrite spectrum)';
            app.Label = uilabel(app.TrimtoUIFigure);
            app.Label.Text = '–';
            app.TrimSelectedButton = uibutton(app.TrimtoUIFigure, 'push');
            app.TrimSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @TrimSelectedButtonPushed, true);
            app.TrimSelectedButton.Text = 'Trim Selected';
            app.CancelButton = uibutton(app.TrimtoUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.TrimtoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimToDlg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrimtoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrimtoUIFigure)
        end
    end
end