classdef pca_options_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PCAOptionsUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        AlgorithmPanel                  matlab.ui.container.Panel
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        DatarangePanel                  matlab.ui.container.Panel
        UsedatarangeCheckBox            matlab.ui.control.CheckBox
        DatarangeEditField              matlab.ui.control.NumericEditField
        DatarangeEditFieldLabel         matlab.ui.control.Label
        data_range_2_edit_field         matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        RandomSelectionButtonGroup      matlab.ui.container.ButtonGroup
        NumberofrandomlyselectedspectraEditField  matlab.ui.control.NumericEditField
        NumberofrandomlyselectedspectraEditFieldLabel  matlab.ui.control.Label
        RandomlyselectmeasurementsegsinglespectraButton  matlab.ui.control.RadioButton
        UseallmeasurementsrandomlyselectspectrafromLAscansButton  matlab.ui.control.RadioButton
        NoselectionusealldataButton     matlab.ui.control.RadioButton
        SelectionMaskPanel              matlab.ui.container.Panel
        CreatenewmaskwhenspectraldatadoesnothaveaselectionmaskCheckBox  matlab.ui.control.CheckBox
        UserandomselectionmaskCheckBox  matlab.ui.control.CheckBox
        NormalizationPanel              matlab.ui.container.Panel
        norm_range_2_edit_field         matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        NormalizationrangeEditField     matlab.ui.control.NumericEditField
        NormalizationrangeEditFieldLabel  matlab.ui.control.Label
        RenormalizespectraCheckBox      matlab.ui.control.CheckBox
        ProcessingoptionsPanel          matlab.ui.container.Panel
        IgnoreNaNsCheckBox              matlab.ui.control.CheckBox
        SetallzerostoNaNCheckBox        matlab.ui.control.CheckBox
        GridLayout2                     matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        main_app ramatguiapp;       % The main (calling) app
    end
    
    properties (Access = public)
        options PCAOptions;
    end

    
    methods (Access = private)
        
        function set_options(app, opts)

            arguments
                app
                opts PCAOptions;
            end

            app.options = opts;

            % set gui
            app.AlgorithmDropDown.Value = opts.algorithm;
            app.UsedatarangeCheckBox.Value = opts.use_range;
            app.DatarangeEditField.Value = opts.range(1);
            app.data_range_2_edit_field.Value = opts.range(2);
            
        end
    end
    
    methods (Access = public)
        
        function opts = get_options(app)
            opts = app.options;
            
            opts.algorithm = app.AlgorithmDropDown.Value;
            opts.use_range = app.UsedatarangeCheckBox.Value;
            opts.range(1) = app.DatarangeEditField.Value;
            opts.range(2) = app.data_range_2_edit_field.Value;

            opts.normalize = app.RenormalizespectraCheckBox.Value;
            opts.normalization_range(1) = app.NormalizationrangeEditField.Value;
            opts.normalization_range(2) = app.norm_range_2_edit_field.Value;

            opts.rand_subset = app.UseallmeasurementsrandomlyselectspectrafromLAscansButton.Value;
            opts.rand_num = app.NumberofrandomlyselectedspectraEditField.Value;

            opts.use_mask = app.UserandomselectionmaskCheckBox.Value;
            opts.create_mask = app.CreatenewmaskwhenspectraldatadoesnothaveaselectionmaskCheckBox.Value;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, opts)
            
            arguments
                app pca_options_dialog;
                main_app ramatguiapp;
                opts.current_opts PCAOptions = PCAOptions;
            end
            
            % Set the calling main app
            app.main_app = main_app;

            % Set options, retrieved from the main app
            app.set_options(opts.current_opts);

        end

        % Selection changed function: RandomSelectionButtonGroup
        function RandomSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.RandomSelectionButtonGroup.SelectedObject;
            
            if selectedButton ~= app.NoselectionusealldataButton
                app.UserandomselectionmaskCheckBox.Enable = true;
            end
            
        end

        % Value changed function: RenormalizespectraCheckBox
        function RenormalizespectraCheckBoxValueChanged(app, event)
            value = app.RenormalizespectraCheckBox.Value;
            
            app.NormalizationrangeEditField.Enable = value;
            app.norm_range_2_edit_field.Enable = value;
            
        end

        % Value changed function: UsedatarangeCheckBox
        function UsedatarangeCheckBoxValueChanged(app, event)
            value = app.UsedatarangeCheckBox.Value;
            
            app.DatarangeEditField.Enable = value;
            app.data_range_2_edit_field.Enable = value;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            opts = app.get_options();

            out("Transferring following options:")
            disp(opts);

            app.main_app.set_pca_options(opts);

            delete(app);

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PCAOptionsUIFigure = uifigure('Visible', 'off');
            app.PCAOptionsUIFigure.Name = 'PCA Options';
            app.GridLayout = uigridlayout(app.PCAOptionsUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ProcessingoptionsPanel = uipanel(app.GridLayout3);
            app.ProcessingoptionsPanel.Title = 'Processing options';
            app.SetallzerostoNaNCheckBox = uicheckbox(app.ProcessingoptionsPanel);
            app.SetallzerostoNaNCheckBox.Text = 'Set all zeros to NaN';
            app.SetallzerostoNaNCheckBox.Value = true;
            app.IgnoreNaNsCheckBox = uicheckbox(app.ProcessingoptionsPanel);
            app.IgnoreNaNsCheckBox.Text = 'Ignore NaNs';
            app.IgnoreNaNsCheckBox.Value = true;
            app.NormalizationPanel = uipanel(app.GridLayout3);
            app.NormalizationPanel.Title = 'Normalization';
            app.RenormalizespectraCheckBox = uicheckbox(app.NormalizationPanel);
            app.RenormalizespectraCheckBox.ValueChangedFcn = createCallbackFcn(app, @RenormalizespectraCheckBoxValueChanged, true);
            app.RenormalizespectraCheckBox.Text = 'Re-normalize spectra';
            app.RenormalizespectraCheckBox.Value = true;
            app.NormalizationrangeEditFieldLabel = uilabel(app.NormalizationPanel);
            app.NormalizationrangeEditFieldLabel.Text = 'Normalization range:';
            app.NormalizationrangeEditField = uieditfield(app.NormalizationPanel, 'numeric');
            app.NormalizationrangeEditField.Value = 500;
            app.Label = uilabel(app.NormalizationPanel);
            app.Label.Text = '-';
            app.norm_range_2_edit_field = uieditfield(app.NormalizationPanel, 'numeric');
            app.norm_range_2_edit_field.Value = 3200;
            app.SelectionMaskPanel = uipanel(app.GridLayout3);
            app.SelectionMaskPanel.Title = 'Selection Mask';
            app.UserandomselectionmaskCheckBox = uicheckbox(app.SelectionMaskPanel);
            app.UserandomselectionmaskCheckBox.Enable = 'off';
            app.UserandomselectionmaskCheckBox.Text = 'Use random selection mask';
            app.UserandomselectionmaskCheckBox.Value = true;
            app.CreatenewmaskwhenspectraldatadoesnothaveaselectionmaskCheckBox = uicheckbox(app.SelectionMaskPanel);
            app.CreatenewmaskwhenspectraldatadoesnothaveaselectionmaskCheckBox.Enable = 'off';
            app.CreatenewmaskwhenspectraldatadoesnothaveaselectionmaskCheckBox.Text = 'Create new mask, when spectral data does not have a selection mask';
            app.CreatenewmaskwhenspectraldatadoesnothaveaselectionmaskCheckBox.Value = true;
            app.RandomSelectionButtonGroup = uibuttongroup(app.GridLayout3);
            app.RandomSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RandomSelectionButtonGroupSelectionChanged, true);
            app.RandomSelectionButtonGroup.Title = 'Random Selection';
            app.NoselectionusealldataButton = uiradiobutton(app.RandomSelectionButtonGroup);
            app.NoselectionusealldataButton.Text = 'No selection, use all data';
            app.NoselectionusealldataButton.Value = true;
            app.UseallmeasurementsrandomlyselectspectrafromLAscansButton = uiradiobutton(app.RandomSelectionButtonGroup);
            app.UseallmeasurementsrandomlyselectspectrafromLAscansButton.Text = 'Use all measurements, randomly select spectra from LA scans';
            app.RandomlyselectmeasurementsegsinglespectraButton = uiradiobutton(app.RandomSelectionButtonGroup);
            app.RandomlyselectmeasurementsegsinglespectraButton.Enable = 'off';
            app.RandomlyselectmeasurementsegsinglespectraButton.Text = 'Randomly select measurements (e.g. single spectra)';
            app.NumberofrandomlyselectedspectraEditFieldLabel = uilabel(app.RandomSelectionButtonGroup);
            app.NumberofrandomlyselectedspectraEditFieldLabel.Text = 'Number of randomly selected spectra:';
            app.NumberofrandomlyselectedspectraEditField = uieditfield(app.RandomSelectionButtonGroup, 'numeric');
            app.NumberofrandomlyselectedspectraEditField.Limits = [0 Inf];
            app.NumberofrandomlyselectedspectraEditField.Value = 100;
            app.DatarangePanel = uipanel(app.GridLayout3);
            app.DatarangePanel.Title = 'Data range';
            app.Label_2 = uilabel(app.DatarangePanel);
            app.Label_2.Text = '-';
            app.data_range_2_edit_field = uieditfield(app.DatarangePanel, 'numeric');
            app.data_range_2_edit_field.Enable = 'off';
            app.data_range_2_edit_field.Value = 1800;
            app.DatarangeEditFieldLabel = uilabel(app.DatarangePanel);
            app.DatarangeEditFieldLabel.Text = 'Data range:';
            app.DatarangeEditField = uieditfield(app.DatarangePanel, 'numeric');
            app.DatarangeEditField.Enable = 'off';
            app.DatarangeEditField.Value = 600;
            app.UsedatarangeCheckBox = uicheckbox(app.DatarangePanel);
            app.UsedatarangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsedatarangeCheckBoxValueChanged, true);
            app.UsedatarangeCheckBox.Text = 'Use data range';
            app.AlgorithmPanel = uipanel(app.GridLayout3);
            app.AlgorithmPanel.Title = 'Algorithm';
            app.AlgorithmDropDownLabel = uilabel(app.AlgorithmPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm:';
            app.AlgorithmDropDown = uidropdown(app.AlgorithmPanel);
            app.AlgorithmDropDown.Items = {'nipals', 'svd', 'svd-nsc', 'eig', 'als'};
            app.AlgorithmDropDown.Value = 'nipals';
            % Show the figure after all components are created
            app.PCAOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pca_options_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PCAOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PCAOptionsUIFigure)
        end
    end
end