classdef ramatguiapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DVRamanToolUIFigure            matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        OpenRecentMenu                 matlab.ui.container.Menu
        emptyMenu                      matlab.ui.container.Menu
        ImportDataMenu                 matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveAsMenu                     matlab.ui.container.Menu
        ExportDataMenu                 matlab.ui.container.Menu
        CloseMenu                      matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        SelectAllMenu                  matlab.ui.container.Menu
        DuplicateMenu                  matlab.ui.container.Menu
        RemoveMenu                     matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        HelpMenu_2                     matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        DataContainerNameLabel         matlab.ui.control.Label
        DataPreviewLabel               matlab.ui.control.Label
        AnalysisPanel                  matlab.ui.container.Panel
        AnalysisLinkRemoveButton       matlab.ui.control.Button
        AnalysisMoveDownButton         matlab.ui.control.Button
        AnalysisMoveUpButton           matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        PlotItemButton                 matlab.ui.control.Button
        SetDefaultButton               matlab.ui.control.Button
        ExportButton_2                 matlab.ui.control.Button
        DumpItemtoWorkspaceButton      matlab.ui.control.Button
        RemoveItemButton               matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        DataItemsTree                  matlab.ui.container.Tree
        DataContainerNode              matlab.ui.container.TreeNode
        DataItemNode                   matlab.ui.container.TreeNode
        UIPreviewAxes                  matlab.ui.control.UIAxes
        AnalysisLabel                  matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        AnalysisMgrTree                matlab.ui.container.Tree
        SubsetTree                     matlab.ui.container.Tree
        Panel                          matlab.ui.container.Panel
        btnRefresh                     matlab.ui.control.Button
        btnDeleteSmall                 matlab.ui.control.Button
        btnImportSmall                 matlab.ui.control.Button
        btnAddGroupSmall               matlab.ui.control.Button
        btnDuplicateSmall              matlab.ui.control.Button
        btnExportSmall                 matlab.ui.control.Button
        DataManagerLabel               matlab.ui.control.Label
        DataMgrTree                    matlab.ui.container.Tree
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        OptionsPanel                   matlab.ui.container.Panel
        AutomaticallyopenLAviewerCheckBox  matlab.ui.control.CheckBox
        AutomaticallyupdatepreviewCheckBox  matlab.ui.control.CheckBox
        SettingsButton                 matlab.ui.control.Button
        DataPanel                      matlab.ui.container.Panel
        NewGroupButton                 matlab.ui.control.Button
        RemoveButton_3                 matlab.ui.control.Button
        DuplicateButton                matlab.ui.control.Button
        ExportButton_3                 matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        WorkspacePanel                 matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        NewButton                      matlab.ui.control.Button
        btnSaveMore                    matlab.ui.control.Button
        SpectralCorrectionPanel        matlab.ui.container.Panel
        AsCopyCheckBox                 matlab.ui.control.CheckBox
        TrimButton                     matlab.ui.control.Button
        CosmicRayRemovalButton         matlab.ui.control.Button
        RemoveBaselineButton           matlab.ui.control.Button
        NormalizeButton                matlab.ui.control.Button
        PlottingPanel                  matlab.ui.container.Panel
        StackedPlotCheckBox            matlab.ui.control.CheckBox
        QuickPlotButton                matlab.ui.control.Button
        PeakAnalysisTab                matlab.ui.container.Tab
        ExtractPeaksPanel              matlab.ui.container.Panel
        MinimumHeightEditField         matlab.ui.control.NumericEditField
        MinimumHeightEditFieldLabel    matlab.ui.control.Label
        PeakAnalysisNegativeCheckbox   matlab.ui.control.CheckBox
        PeakAnalysisTarget             matlab.ui.container.ButtonGroup
        FromSelectedDataItemButton     matlab.ui.control.RadioButton
        FromSelectioninDataManagerButton  matlab.ui.control.RadioButton
        MinimumProminenceEditField     matlab.ui.control.NumericEditField
        MinimumProminenceEditFieldLabel  matlab.ui.control.Label
        PeakTablePreviewButton         matlab.ui.control.Button
        ExtractPeakTableButton         matlab.ui.control.Button
        SpectralAnalysisTab            matlab.ui.container.Tab
        tSNEPanel                      matlab.ui.container.Panel
        DistanceMethodDropDown         matlab.ui.control.DropDown
        DistanceMethodDropDownLabel    matlab.ui.control.Label
        tsneAlgorithmDropDown          matlab.ui.control.DropDown
        AlgorithmDropDown_2Label       matlab.ui.control.Label
        tSNEOptions                    matlab.ui.container.ButtonGroup
        tsneStartRangeField            matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        tsneEndRangeField              matlab.ui.control.NumericEditField
        cm1EditFieldLabel_2            matlab.ui.control.Label
        tSNERangeButton                matlab.ui.control.RadioButton
        tsneEntireSpectrumButton       matlab.ui.control.RadioButton
        NewTSNE                        matlab.ui.control.Button
        PrincipalComponentAnalysisPanel  matlab.ui.container.Panel
        pca_options_button             matlab.ui.control.Button
        AlgorithmDropDown              matlab.ui.control.DropDown
        AlgorithmLabel                 matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PCAStartRangeField             matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        PCAEndRangeField               matlab.ui.control.NumericEditField
        cm1EditFieldLabel              matlab.ui.control.Label
        PCARangeButton                 matlab.ui.control.RadioButton
        PCAEntireSpectrumButton        matlab.ui.control.RadioButton
        PCAAnalyzeButton               matlab.ui.control.Button
        PlottingPanel_2                matlab.ui.container.Panel
        SpectralPlotButton             matlab.ui.control.Button
        AnalysisSubsetPanel            matlab.ui.container.Panel
        OpenDataTableButton            matlab.ui.control.Button
        AutoassignsamplesreplicatesButton  matlab.ui.control.Button
        SubsetNameEditField            matlab.ui.control.EditField
        SubsetNameEditFieldLabel       matlab.ui.control.Label
        AreaScanTab                    matlab.ui.container.Tab
        FiltersPanel                   matlab.ui.container.Panel
        ManageFiltersButton            matlab.ui.control.Button
        OverviewPanel_2                matlab.ui.container.Panel
        AreaScanOverview               matlab.ui.control.TextArea
        PCATab                         matlab.ui.container.Tab
        DataStructurePanel             matlab.ui.container.Panel
        PCADataStructureTree           matlab.ui.container.Tree
        OverviewPanel                  matlab.ui.container.Panel
        RemovePCA_Button               matlab.ui.control.Button
        RecalculateButton              matlab.ui.control.Button
        PCADescription                 matlab.ui.control.TextArea
        DAnalysisPanel_2               matlab.ui.container.Panel
        PCAErrorEllipseCheckBox        matlab.ui.control.CheckBox
        OptionsButton                  matlab.ui.control.Button
        ConfidenceIntervalButtonGroup  matlab.ui.container.ButtonGroup
        RadioConfInt999                matlab.ui.control.RadioButton
        RadioConfInt990                matlab.ui.control.RadioButton
        RadioConfInt950                matlab.ui.control.RadioButton
        Scores2DScatterPlotButton      matlab.ui.control.Button
        DAnalysisPanel                 matlab.ui.container.Panel
        MultipleScoresButton           matlab.ui.control.Button
        ScoresButtonX_Preview_4        matlab.ui.control.Button
        ScoresButtonX_Preview_3        matlab.ui.control.Button
        AverageScoresPerSample         matlab.ui.control.CheckBox
        ScoresButtonY_Preview          matlab.ui.control.Button
        ScoresButtonX_Preview          matlab.ui.control.Button
        PCYSpinner                     matlab.ui.control.Spinner
        PCYSpinnerLabel                matlab.ui.control.Label
        PCXSpinner                     matlab.ui.control.Spinner
        PCXSpinnerLabel                matlab.ui.control.Label
        ScoresButtonY                  matlab.ui.control.Button
        ScoresButtonX                  matlab.ui.control.Button
        LoadingsButtonX                matlab.ui.control.Button
        LoadingsButtonX_Preview        matlab.ui.control.Button
        LoadingsButtonY                matlab.ui.control.Button
        LoadingsButtonY_Preview_2      matlab.ui.control.Button
        contxtSaveAs                   matlab.ui.container.ContextMenu
        SaveMenu_2                     matlab.ui.container.Menu
        SaveAsMenu_2                   matlab.ui.container.Menu
        contxtDataMgrTreeNode          matlab.ui.container.ContextMenu
        AddtoNewAnalysisMenu           matlab.ui.container.Menu
        AddtoMenu                      matlab.ui.container.Menu
        MovetoMenu                     matlab.ui.container.Menu
        DumptoWorkspaceMenu            matlab.ui.container.Menu
        emptyMenuPlaceholder           matlab.ui.container.Menu
        contxtSubsetTreeNode           matlab.ui.container.ContextMenu
        AssigntoNewGroupMenu           matlab.ui.container.Menu
        AssigntoMenu                   matlab.ui.container.Menu
        RemoveMenu_2                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        DataSet
        tblGroups
        SampleSelectionLogic
        HighlightedSampleIndices
        ImportDialog
        SpectralPlotDialog
        PCAResult = struct;     % Struct Containing PCA Results
        UIHighlighted = struct;
        
        rootdir % Root directory of executable
    end
    
    properties (Access = public)
        prj % The handle to the Project() Object
        OpenedDialogs = struct;
        pca_options = PCAOptions;
    end

    properties (Access = public, Dependent)
        selected;                   % Get selection of data manager tree
        selected_datacontainers;    % Get selected data containers only
        selected_items;
    end

    % Dependent properties
    methods
        function selection = get.selected(app)
            %SELECTED
            %   Returns the selected nodes

            selection = app.DataMgrTree.SelectedNodes;
        end

        function selection = get.selected_datacontainers(app)
            %SELECTED_DATACONTAINERS
            %   Returns the selected data containers

            selection = [];

            selected_nodes = app.selected;
            selection = vertcat(selected_nodes.NodeData);

            if isempty(selection)
                return;
            end

        end

        function selection = get.selected_items(app)
            %SELECTED_ITEMS
            %   Returns selected data items

            selected_nodes = app.DataItemsTree.SelectedNodes;

            if isempty(selected_nodes)
                selection = [];
            else
                selection = vertcat( selected_nodes.NodeData );
            end
        end

        
        function root = get_figure_root(app)
            root = app.DVRamanToolUIFigure;
        end
        
        function opts = get_pca_options(app)
            opts = app.pca_options;
        end
        
        function set_pca_options(app, opts)
            arguments
                app
                opts PCAOptions;
            end

            app.pca_options = opts;
            
            % set gui
            app.PCARangeButton.Value = opts.use_range;
            app.PCAStartRangeField.Value = opts.range(1);
            app.PCAEndRangeField.Value = opts.range(2);
            app.AlgorithmDropDown.Value = opts.algorithm;


        end
    end
    
    methods (Access = private)
        
        function updatePreviewPlot(app, data)
            
            updateDataPreview(app, data);
            
        end
        
        function loadDataSet(app)
            
            load_dataset(app.prj, App=app);
            
        end
        
        function saveDataSet(app)
            
            save_dataset(app.prj, App=app);
            
        end
        
        function results = getSelectedIndices(app,logicArray)
            %GETSELECTEDINDICES
            %   Deprecated

            warning("The function GETSELECTEDINDICES is deprecated.")
%             logicArray = app.SampleSelectionLogic;

            % Convert logic array to array of actual sample indices
            % e.g. [0 1 0 1] becomes [2 4]
%             results = transpose(1:length(logicArray)) .* logicArray;
%             results(results==0) = [];
        end


        function createGroup(app)
            groups = table([1],true,"Group1",'VariableNames',{'ID','Selected','GroupName'});
            
            app.tblGroups = groups;
        end
        
        function quickPlot(app, data)
            %QUICKPLOT
            
            data.plot();
            
        end


        function assignGroupToSamples(app, group, samples)
            % Assign a group to multiple selected samples.
            
            [app.DataSet(samples).group] = deal(group);
            
            updateTables(app);
        end
        
        function newGroupID = addGroup(app)
            % Get list of current groups
            currentTable = app.tblGroups;    
            lastID = max(currentTable.ID);
            
            % Create a new group with a new ID
            newGroupID = lastID + 1;
            newName = sprintf("%s %d", "New Group", newGroupID);
            newRow = table(newGroupID, logical([1]), newName, 'VariableNames', {'ID','Selected','GroupName'});
            
            % Append to group table
            newTable = [currentTable; newRow];
            app.tblGroups = newTable;
            
            % Update GUI
            updateTables(app);
        end
        
        function status = deleteGroup(app, selectedGroupRows)
        % DELETEGROUP()
        % selectedGroupRows: states group row to be deleted, NOT ID.
            
            status = 0;
            
            if (size(selectedGroupRows) == 0)
                % Nothing has been selected, so nothing can be deleted.
                warning('No groups have been selected.');
                return
            end
            
            % Get list of current groups
            currentTable = app.tblGroups;
            selectedGroupIDs = currentTable.ID( selectedGroupRows );
            
            % Check if at least one group will remain after deletion
            nEntriesTable = height(currentTable);
            nDeletions = size(selectedGroupRows);
            
            if ((nEntriesTable - nDeletions(1)) < 1)
                warning('At least one group is needed for a dataset.');
                status = 1;
                return
            else
                
                % Check if any data is appended to the groups that are
                % about to be deleted.
                
                data = app.DataSet;
                currentGroupAssignments = vertcat( data.group );
                logicalMembers = ismember(currentGroupAssignments, selectedGroupIDs);
                                
                if (size(logicalMembers) > 0)
                    % The groups that are about to be deleted have data
                    % assigned to them
                    warning('The selected groups currently have assigned data to them. These samples have been assigned to a new group.');
                    
                    % Create a new group and assign data to it.
                    newGroupID = addGroup(app);
                    [data( logicalMembers ).group] = deal(newGroupID);
                    
                    % Update current table
                    currentTable = app.tblGroups;
                end
                
                try
                    currentTable( selectedGroupRows, : ) = [];
                    app.tblGroups = currentTable;
                catch
                    warning('Attempt to remove selected table rows failed.');
                    status = 2;
                end
                
            end
            
            % Update GUI
            updateTables(app);
        end
       
    end
    
    methods (Access = public)

        function pcs = get_selected_pcs(app)
            %GET_SELECTED_PCS Returns Selected principal components

            pcx = app.PCXSpinner.Value;
            pcy = app.PCYSpinner.Value;

            pcs = [pcx pcy];
            
        end
        
        function loadImportedData(app, data)
            %LOADIMPORTEDDATA
            % app:      Main App
            % data:     nx1 DataContainer
            
            % Append Data to Project
            if class(data) == "DataContainer"
                app.prj.append_data(data);
            end

            if class(data) == "Project"
                app.prj.append_project(data);
            end
            
            % Update GUI Data Manager
            app.updatemgr();
        end
        
        function trim_spectra(app, range, options)

            arguments
                app;
                range double = [];
                options.copy logical = false;
            end
            
            data = app.selected_datacontainers;
            
            data.trim_spectra(range, copy=options.copy);
        end
        
        function results = getSelectedMeasurements(app)
            results = app.HighlightedSampleIndices;
        end


        function updatemgr(app, options)
            %UPDATEMGR Wrapper for non member method updateappmgr()
            
            arguments
                app;
                options.Parts = [1,2,3];
            end
            
            updateappmgr(app, Parts=options.Parts);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, initVar)
            %STARTUPFCN Initialises the GUI app
            
            % Unpack initialisation data from initVar argument
            app.prj = initVar.prj;  % Set working Project
            app.rootdir = initVar.rootdir;
            
            % Set Window Title
            app.DVRamanToolUIFigure.Name = app.prj.name;
            
            %loadDataSet(app);
            
            %Set struct
            app.UIHighlighted.samples = [0];
            app.UIHighlighted.groups = [0];
            
            % First update of managers
            app.updatemgr;
            
            
            % Set up sub windows
            app.OpenedDialogs.SpectralAreaDataViewer = SpecAreaDataViewer.empty();

            % Set default pca options
            app.pca_options = PCAOptions;
        end

        % Callback function
        function UISamplesTableCellSelection(app, event)
            % Event indices are given in [row col]
            % Store highlighted rows, that represent the selected samples.
            indices = event.Indices;
            indices = unique(indices(:,1));
            
            app.HighlightedSampleIndices = indices;
            app.UIHighlighted.samples = indices;
            
            updatePreviewPlot(app,indices);
            
            disp(indices);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            saveDataSet(app);
        end

        % Button pushed function: Scores2DScatterPlotButton
        function Scores2DScatterPlotButtonPushed(app, event)
            % Retrieve PC Axes Spinner Values
            pcaxx = app.PCXSpinner.Value;
            pcaxy = app.PCYSpinner.Value;
            pcax = [pcaxx pcaxy];

            % Retrieve Results from PCAResult
            if isempty(app.prj.ActiveAnalysisResult)
                warning("No PCA Result found.");
                return
            end
            
            pcares = app.prj.ActiveAnalysisResult;
            
            % Retrieve Plotting Options
            showEllipsesBool = app.PCAErrorEllipseCheckBox.Value;
            
            % Plot PCA Scores
            pcares.data.scoresscatter(pcax, ErrorEllipse=showEllipsesBool);
        end

        % Callback function
        function UISamplesTableCellEdit(app, event)
            % Data in sample selection table has been changed by user
            indices = event.Indices;
            newData = event.NewData;
            
            changedRow = indices(1);
            changedCol = indices(2);
            switch changedCol
                case 1 % Selection Changed
                    app.SampleSelectionLogic(changedRow) = newData;
                    app.DataSet(changedRow).selected = newData;
                case 5 % Group Changed
                    % Get Group ID, instead of full name
                    splittedString = strsplit(string(newData), ':');
                    newGroupID = str2num(splittedString(1));
                    
                    % Set Group ID
                    app.DataSet(changedRow).group = newGroupID;
            end
            
            
            idx = getSelectedIndices(app,app.SampleSelectionLogic);
            display(idx);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            loadDataSet(app);
        end

        % Callback function
        function SelectAllNoneButtonPushed(app, event)
            selection = app.SampleSelectionLogic;
            nSamples = length(selection);
            
            if (sum(selection) == length(selection))
                selection = zeros(nSamples,1);          % Table Set
                [app.DataSet.selected] = deal(false);   % Struct Set
            else
                selection = ones(nSamples,1);           % Table Set
                [app.DataSet.selected] = deal(true);    % Struct Set
            end
            
            app.SampleSelectionLogic = logical(selection); % Table Set
            
            updateTables(app);
        end

        % Callback function
        function ButtonGroupAddPushed(app, event)
            addGroup(app);
        end

        % Callback function
        function UIGroupsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            changedRow = indices(1);
            changedCol = indices(2);
            
            app.tblGroups{changedRow,changedCol} = newData;
                        
            updateTables(app);

        end

        % Button pushed function: btnImportSmall
        function btnImportSmallPushed(app, event)
            app.ImportDialog = ImportDialogApp(app);
        end

        % Button pushed function: PCAAnalyzeButton
        function PCAAnalyzeButtonPushed(app, event)
            % Create new PCA analysis
            
            
            % Create PCA
            pcaresult = app.prj.create_pca(app.pca_options);
            if isempty(pcaresult), return; end
            
            % Add to analysis results
            app.prj.add_analysis_result(pcaresult);
            
            % Update managers
            app.updatemgr;

        end

        % Button pushed function: QuickPlotButton
        function QuickPlotButtonPushed(app, event)
            %QUICKPLOT
            
            if isempty(app.DataMgrTree.SelectedNodes)
                % Nothing Selected
                return
                
            end
            
            % TO DO WRITE FUNCTION FOR DATA SELCTION
            
            % Get associated data containers
            data = vertcat( app.DataMgrTree.SelectedNodes.NodeData );
            
            if ~(all( vertcat( data.dataType ) == "SpecData" ) || (numel(data) == 1 && data.dataType == "ImageData" ))
                % Non-uniform data types have been selected
            
                return;
                
            end
            
            quickPlot(app, data);
            
        end

        % Button pushed function: btnAddGroupSmall
        function btnAddGroupSmallPushed(app, event)
            % Add new group
            app.prj.add_group("New Group");
            
            % Update GUI Data Manager
            app.updatemgr();
        end

        % Button pushed function: LoadingsButtonX
        function LoadingsButtonXPushed(app, event)
            % Get PCA axis
            pcax = app.PCXSpinner.Value;

            % Get current pca result
            pcar = app.prj.get_active_pca_result();
            if isempty(pcar), return; end

            % Add loadings spectrum
            pcar.add_loadings_spectrum(pcax);

            % Update Data Items tree
            update_data_items_tree(app, pcar.parent_container);
        end

        % Callback function
        function AssignButtonPushed(app, event)
            if (app.UIHighlighted.groups ~= 0 && min(app.UIHighlighted.samples) ~= 0)
                if size(app.UIHighlighted.groups) > 1
                    warning('You can only assign one group to the samples.');
                else
                    
                    group = app.UIHighlighted.groups;
                    samples = app.UIHighlighted.samples;
                    
                    try
                        assignGroupToSamples(app,group,samples)
                    catch ME
                        warning('Could not assign group to samples.');
                    end
                end
            end
        end

        % Callback function
        function UIGroupsTableCellSelection(app, event)
            % Event indices are given in [row col]
            % Store highlighted rows, that represent the selected samples.
            indices = event.Indices;
            indices = unique(indices(:,1));
            
            app.UIHighlighted.groups = indices;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            app.OpenedDialogs.AboutDlg = AboutDlg(app);
        end

        % Button pushed function: TrimButton
        function TrimButtonPushed(app, event)
            app.OpenedDialogs = TrimToDlg(app, copy=app.AsCopyCheckBox.Value);
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            as_copy = app.AsCopyCheckBox.Value;
            
            % Get object handles
            objh = vertcat( app.DataMgrTree.SelectedNodes.NodeData );
            
            % Normalize Data
            objh.normalize_spectrum(copy=as_copy);
        end

        % Button pushed function: LoadingsButtonY
        function LoadingsButtonYPushed(app, event)
            % Get PCA axis
            pcax = app.PCYSpinner.Value;

            % Get current pca result
            pcar = app.prj.get_active_pca_result();
            if isempty(pcar), return; end

            % Add loadings spectrum
            pcar.add_loadings_spectrum(pcax);

            % Update Data Items tree
            update_data_items_tree(app, pcar.parent_container);
        end

        % Value changed function: PCAErrorEllipseCheckBox
        function PCAErrorEllipseCheckBoxValueChanged(app, event)
            value = app.PCAErrorEllipseCheckBox.Value;
            
            % Change visibility
            if (value == true)
                app.RadioConfInt950.Enable = true;
                app.RadioConfInt990.Enable = true;
                app.RadioConfInt999.Enable = true;
            else
                app.RadioConfInt950.Enable = false;
                app.RadioConfInt990.Enable = false;
                app.RadioConfInt999.Enable = false;
            end
            
            % Update scatter plot
            updateScoresScatterPlot(app);
        end

        % Callback function
        function ButtonGroupDeletePushed(app, event)
            % Retrieve selected groups
            groupID = app.UIHighlighted.groups;
            
            % Delete groups
            try
                deleteGroup(app, groupID);
            catch
                warning('An error has occurred: Could not properly delete selected groups.');
            end
        end

        % Callback function: ImportButton, ImportDataMenu
        function ImportButtonPushed(app, event)
            app.ImportDialog = ImportDialogApp(app);
        end

        % Button pushed function: btnDuplicateSmall
        function btnDuplicateSmallButtonPushed(app, event)
            
        end

        % Button pushed function: btnSaveMore
        function btnSaveMoreButtonPushed(app, event)
            pos = app.btnSaveMore.Position + app.btnSaveMore.Parent.Position + app.btnSaveMore.Parent.Parent.Position + app.btnSaveMore.Parent.Parent.Parent.Position;
            open(app.contxtSaveAs, pos(1), pos(2));
        end

        % Context menu opening function: contxtDataMgrTreeNode
        function contxtDataMgrTreeNodeContextMenuOpening(app, event)

        end

        % Selection changed function: SubsetTree
        function SubsetTreeSelectionChanged(app, event)
            selectedNodes = app.SubsetTree.SelectedNodes;
            
            % Set new active Analysis Set
            app.prj.ActiveAnalysis = selectedNodes.NodeData;
            
            % Update GUI Managers
            app.updatemgr(Parts=3);
            
            % Switch to Tab
            app.TabGroup.SelectedTab = app.SpectralAnalysisTab;
            
        end

        % Context menu opening function: contxtSubsetTreeNode
        function contxtSubsetTreeNodeContextMenuOpening(app, event)
            % This callback is called when the context menu of a analysis
            % subset tree node is opened. It filld the context menu with
            % the available analysis subset groups.
            
            % The called menu
            ctm = event.Source;
            ctm.Children.delete();
            
            % The analysis subset whose items are currently listed
            analysis = app.prj.ActiveAnalysis;
            
            mh = uimenu(ctm, ...
                'Text', 'Assign to New Group', ...
                'Callback', @AssigntoNewGroupMenuSelected);
            
            if numel(analysis.GroupSet) > 0
                % The active analysis subset has groups
                mh = uimenu(ctm, 'Text', 'Assign to ...');
                
                for i = 1:numel(analysis.GroupSet)
                    group = analysis.GroupSet(i);
                    name = group.display_name;
                    
                    menunode = uimenu(mh, ...
                        'Text', name, ...       % Name of group
                        'Callback', {@AssigntoMenuSelected, group}); 
                end
            end
            
            mh = uimenu(ctm, ...
                'Text', 'Remove', ...
                'Callback', @RemoveMenuSelected);
            
            %% Callback nested functions
            
            function RemoveMenuSelected(varargin)
                % User has selected <REMOVE> in contxtSubsetItemTreeNode
            
                % TO-DO:
                % Get selected tree nodes
                h = vertcat(app.SubsetItemsTree.SelectedNodes.NodeData);
                
                h.delete();
            end

            % Menu selected function: AddtoMenu
            function AssigntoMenuSelected(varargin)
                % User has selected <ASSIGN TO ...> in
                % contxtSubsetItemTreeNode
                newgroup = varargin{3};
                
                % Get handles to selected data containers
%                 nodes = 
                h = vertcat(app.SubsetItemsTree.SelectedNodes.NodeData);
                
                analysis.move_data_to_group(h, newgroup);
                
                % Update GUI Managers
                app.updatemgr(Parts=3);
            end
            
            % Menu selected function: AddtoNewAnalysisMenu
            function AssigntoNewGroupMenuSelected(varargin)
                % User has selected <ADD TO NEW ANALYSIS> in
                % contxtSubsetItemTreeNode
                newgroup = analysis.add_group();
                
                % Get handles to selected data containers
                h = vertcat(app.SubsetItemsTree.SelectedNodes.NodeData);
                
                analysis.move_data_to_group(h, newgroup);
                
                % Update GUI Managers
                app.updatemgr(Parts=3);
            end            
        end

        % Callback function
        function SubsetItemsTreeNodeTextChanged(app, event)
            % The changed object (DataContainer or AnalysisGroup)
            obj = event.Node.NodeData;
            
            % Set new name
            newname = event.Text;
            obj.name = newname;
            
        end

        % Selection changed function: DataMgrTree
        function DataMgrTreeSelectionChanged(app, event)
            % User has changed selection in the DataMgrTree
            
            update_app_selection_changed(app, event);
            
        end

        % Value changed function: PCXSpinner
        function PCXSpinnerValueChanged(app, event)

            % Update the scores scatter preview
            updateScoresScatterPlot(app);
            
        end

        % Value changed function: PCYSpinner
        function PCYSpinnerValueChanged(app, event)
            
            % Update the scores scatter preview
            updateScoresScatterPlot(app);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            load_dataset(App=app);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            save_dataset(App=app);
            
        end

        % Button pushed function: RemoveBaselineButton
        function RemoveBaselineButtonPushed(app, event)
            
            objh = vertcat(app.DataMgrTree.SelectedNodes.NodeData);
            
            BgSubApp(app, DataObj=objh);
            
        end

        % Button pushed function: ManageFiltersButton
        function ManageFiltersButtonPushed(app, event)
            % Open Filter Manager
            
            %fmgr = FilterManager()
            
        end

        % Button pushed function: SpectralPlotButton
        function SpectralPlotButtonPushed(app, event)
            
            if isempty(app.prj.ActiveAnalysis)
                return
            end
            
            app.prj.ActiveAnalysis.plot();
            
        end

        % Node text changed function: SubsetTree
        function SubsetTreeNodeTextChanged(app, event)
            % User changed name of analysis subset
            
            node = event.Node;
            analysissubset = node.NodeData;
            
            % Change Name
            analysissubset.name = event.Text;
            
            % Update UI and dont permit empty names (take DisplayName)
            app.SubsetNameEditField.Value = analysissubset.display_name;
            node.Text = analysissubset.display_name;
            
            
        end

        % Button pushed function: btnDeleteSmall
        function btnDeleteSmallButtonPushed(app, event)
            selection = app.DataMgrTree.SelectedNodes;

            % Get handles
            h = vertcat(selection.NodeData);
            
            deleteItems(h, nodes=selection);
            
        end

        % Button pushed function: RemoveButton_3
        function RemoveButton_3Pushed(app, event)
            selection = app.DataMgrTree.SelectedNodes;

            % Get handles
            h = vertcat(selection.NodeData);
            
            deleteItems(h, nodes=selection);
            
        end

        % Callback function
        function SubsetItemsTreeCheckedNodesChanged(app, event)
            % Update the selection of the analysis
            
            checkedNodes = app.SubsetItemsTree.CheckedNodes;
            
            app.prj.ActiveAnalysis.set_selection(checkedNodes);
        end

        % Button pushed function: ExtractPeakTableButton
        function ExtractPeakTableButtonPushed(app, event)
            % Get handles
            if app.FromSelectioninDataManagerButton.Value
                handles = vertcat(app.DataMgrTree.SelectedNodes.NodeData);
            else
                if isempty(app.DataItemsTree.SelectedNodes), return; end
                handles = app.DataItemsTree.SelectedNodes.NodeData;
            end

            % Extract peak table
            for handle = handles'
                if isa(handle, 'DataContainer')
                    if handle.dataType ~= "SpecData", continue; end
                    handle.Data.add_peak_table(min_prominence=app.MinimumProminenceEditField.Value, ...
                        negative_peaks = app.PeakAnalysisNegativeCheckbox.Value);
                end
                if isa(handle, 'DataItem')
                    if ~isa(handle, "SpecDataABC"), continue; end
                    handle.add_peak_table(min_prominence=app.MinimumProminenceEditField.Value, ...
                        negative_peaks = app.PeakAnalysisNegativeCheckbox.Value);
                end
            end

            % Update Data Items tree
            update_data_items_tree(app, handle);
        end

        % Button pushed function: PeakTablePreviewButton
        function PeakTablePreviewButtonPushed(app, event)
            % Get handles
            if app.FromSelectioninDataManagerButton.Value
                handle = vertcat(app.DataMgrTree.SelectedNodes.NodeData);

                % only plot for one data container
                if numel(handle) > 1, return; end
                if class(handle) ~= "DataContainer", if handle.dataType ~= "SpecData", return; end, end
                specdat = handle.Data;
            else
                if isempty(app.DataItemsTree.SelectedNodes), return; end
                handle = app.DataItemsTree.SelectedNodes.NodeData;
                if ~isa(handle, "SpecDataABC"), return; end
                specdat = handle;
            end
            
            % Preview Peak Table
            specdat.preview_peak_table(Axes = app.UIPreviewAxes, ...
                min_prominence = app.MinimumProminenceEditField.Value, ...
                negative_peaks = app.PeakAnalysisNegativeCheckbox.Value);
        end

        % Node text changed function: DataMgrTree
        function DataMgrTreeNodeTextChanged(app, event)
            fprintf('Renaming: %s to %s\n', event.PreviousText, event.Text);

            % Rename item
            if ~isempty(event.Node.NodeData)
                event.Node.NodeData.name = event.Text;
            end
            
        end

        % Value changed function: StackedPlotCheckBox
        function StackedPlotCheckBoxValueChanged(app, event)
%             value = app.StackedPlotCheckBox.Value;
            
            update_app_selection_changed(app, event);
        end

        % Button pushed function: DumpItemtoWorkspaceButton
        function DumpItemtoWorkspaceButtonPushed(app, event)
            %DUMPITEMTOWORKSPACEBUTTONPUSHED
            %   Callback: user pushed the button to dump the currently
            %   selected data item to the workspace

            dump_selection(app.selected_items);
        end

        % Button pushed function: RemoveItemButton
        function RemoveItemButtonPushed(app, event)
            selection = app.DataItemsTree.SelectedNodes;
            if isempty(selection), return; end
            selection.NodeData.remove();
            update_node(selection, "remove");
        end

        % Button pushed function: AnalysisMoveUpButton
        function AnalysisMoveUpButtonPushed(app, event)
            selection = app.AnalysisMgrTree.SelectedNodes;
            if isempty(selection), return; end
            selection.NodeData.moveup();
            update_node(selection, "moveup");
        end

        % Button pushed function: AnalysisMoveDownButton
        function AnalysisMoveDownButtonPushed(app, event)
            selection = app.AnalysisMgrTree.SelectedNodes;
            if isempty(selection), return; end
            selection.NodeData.movedown();
            update_node(selection, "movedown");
        end

        % Button pushed function: AnalysisLinkRemoveButton
        function AnalysisLinkRemoveButtonPushed(app, event)
            selection = app.AnalysisMgrTree.SelectedNodes;
            if isempty(selection), return; end

            links = vertcat(selection.NodeData);
            links.remove();
            update_node(selection, "remove");
        end

        % Button pushed function: RecalculateButton
        function RecalculateButtonPushed(app, event)
            if isempty(app.prj.ActiveAnalysis), return; end
            pcares = app.prj.ActiveAnalysisResult.data;
            pcares.recalculate();

            ax = app.UIPreviewAxes;
            pcares.plot(Axes=ax, PCs=app.get_selected_pcs);
        end

        % Button pushed function: LoadingsButtonX_Preview
        function LoadingsButtonX_PreviewPushed(app, event)
            % Get PCA axis
            pcax = app.PCXSpinner.Value;

            % Get current pca result
            pcar = app.prj.get_active_pca_result();
            if isempty(pcar), return; end

            % Add loadings spectrum
            pcar.plot_loadings_spectrum(pcax);
        end

        % Button pushed function: LoadingsButtonY_Preview_2
        function LoadingsButtonY_Preview_2Pushed(app, event)
            % Get PCA axis
            pcax = app.PCYSpinner.Value;

            % Get current pca result
            pcar = app.prj.get_active_pca_result();
            if isempty(pcar), return; end

            % Add loadings spectrum
            pcar.plot_loadings_spectrum(pcax);
        end

        % Button pushed function: PlotItemButton
        function PlotItemButtonPushed(app, event)
            selection = app.selected_items;
            if isempty(selection), return; end
            selection.plot();
        end

        % Button pushed function: NewTSNE
        function NewTSNEButtonPushed(app, event)
            % Create new t-SNE analysis
            
            % PCA Options from GUI
            range = [];
            
            if app.tSNERangeButton.Value
                
                range = zeros(2, 1);
                range(1) = app.tsneStartRangeField.Value;
                range(2) = app.tsneEndRangeField.Value;
                
            end

            algorithm = app.tsneAlgorithmDropDown.Value;
            method = app.DistanceMethodDropDown.Value;
            
            % Create PCA
            analysis = app.prj.ActiveAnalysis;
            if isempty(analysis), return; end

            tsneres = analysis.compute_tsne(range=range, algorithm=algorithm, method=method);
            if isempty(tsneres), return; end

            result = AnalysisResultContainer(app.prj);
            result.append_child(tsneres);
            result.name = tsneres.name;

            app.prj.add_analysis_result(result);
            app.updatemgr;
        end

        % Button pushed function: btnRefresh
        function btnRefreshButtonPushed(app, event)
            updateappmgr(app,Parts=1);
        end

        % Button pushed function: AutoassignsamplesreplicatesButton
        function AutoassignsamplesreplicatesButtonPushed(app, event)
            app.prj.ActiveAnalysis.auto_assign_samples();
            updateappmgr(app, Parts=3)
        end

        % Node text changed function: AnalysisMgrTree
        function AnalysisMgrTreeNodeTextChanged(app, event)
            fprintf('Renaming: %s to %s\n', event.PreviousText, event.Text);

            % Rename item
            if ~isempty(event.Node.NodeData)
                event.Node.NodeData.name = event.Text;
            end
            
        end

        % Button pushed function: pca_options_button
        function pca_options_buttonPushed(app, event)
            
            current_options = app.get_pca_options();
            
            pca_options_dialog(app, current_opts=current_options);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            app.pca_options.use_range = app.PCARangeButton.Value;
        end

        % Value changed function: PCAStartRangeField
        function PCAStartRangeFieldValueChanged(app, event)
            value = app.PCAStartRangeField.Value;
            app.pca_options.range(1) = value;
        end

        % Value changed function: PCAEndRangeField
        function PCAEndRangeFieldValueChanged(app, event)
            value = app.PCAEndRangeField.Value;
            app.pca_options.range(2) = value;
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            app.pca_options.algorithm = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DVRamanToolUIFigure = uifigure('Visible', 'off');
            app.DVRamanToolUIFigure.Name = 'DV Raman Tool';
            app.FileMenu = uimenu(app.DVRamanToolUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Separator = 'on';
            app.OpenMenu.Text = 'Open...';
            app.OpenRecentMenu = uimenu(app.FileMenu);
            app.OpenRecentMenu.Text = 'Open Recent';
            app.emptyMenu = uimenu(app.OpenRecentMenu);
            app.emptyMenu.Text = '*empty*';
            app.ImportDataMenu = uimenu(app.FileMenu);
            app.ImportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportDataMenu.Text = 'Import Data...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.ExportDataMenu = uimenu(app.FileMenu);
            app.ExportDataMenu.Text = 'Export Data...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            app.EditMenu = uimenu(app.DVRamanToolUIFigure);
            app.EditMenu.Text = 'Edit';
            app.SelectAllMenu = uimenu(app.EditMenu);
            app.SelectAllMenu.Text = 'Select All';
            app.DuplicateMenu = uimenu(app.EditMenu);
            app.DuplicateMenu.Text = 'Duplicate';
            app.RemoveMenu = uimenu(app.EditMenu);
            app.RemoveMenu.Text = 'Remove';
            app.HelpMenu = uimenu(app.DVRamanToolUIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.DVRamanToolUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.PlottingPanel = uipanel(app.GeneralTab);
            app.PlottingPanel.Title = 'Plotting';
            app.QuickPlotButton = uibutton(app.PlottingPanel, 'push');
            app.QuickPlotButton.ButtonPushedFcn = createCallbackFcn(app, @QuickPlotButtonPushed, true);
            app.QuickPlotButton.Text = 'Quick Plot...';
            app.StackedPlotCheckBox = uicheckbox(app.PlottingPanel);
            app.StackedPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @StackedPlotCheckBoxValueChanged, true);
            app.StackedPlotCheckBox.Text = 'Stacked Plot';
            app.SpectralCorrectionPanel = uipanel(app.GeneralTab);
            app.SpectralCorrectionPanel.Title = 'Spectral Correction';
            app.NormalizeButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize...';
            app.RemoveBaselineButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.RemoveBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveBaselineButtonPushed, true);
            app.RemoveBaselineButton.Text = 'Remove Baseline...';
            app.CosmicRayRemovalButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.CosmicRayRemovalButton.Enable = 'off';
            app.CosmicRayRemovalButton.Text = 'Cosmic Ray Removal';
            app.TrimButton = uibutton(app.SpectralCorrectionPanel, 'push');
            app.TrimButton.ButtonPushedFcn = createCallbackFcn(app, @TrimButtonPushed, true);
            app.TrimButton.Text = 'Trim...';
            app.AsCopyCheckBox = uicheckbox(app.SpectralCorrectionPanel);
            app.AsCopyCheckBox.Text = 'As Copy';
            app.WorkspacePanel = uipanel(app.GeneralTab);
            app.WorkspacePanel.Title = 'Workspace';
            app.btnSaveMore = uibutton(app.WorkspacePanel, 'push');
            app.btnSaveMore.ButtonPushedFcn = createCallbackFcn(app, @btnSaveMoreButtonPushed, true);
            app.btnSaveMore.Text = {' '; '▼'};
            app.NewButton = uibutton(app.WorkspacePanel, 'push');
            app.NewButton.Icon = 'New_24.png';
            app.NewButton.Text = 'New';
            app.OpenButton = uibutton(app.WorkspacePanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = 'Folder_24.png';
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.WorkspacePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'Save_24.png';
            app.SaveButton.Text = 'Save';
            app.DataPanel = uipanel(app.GeneralTab);
            app.DataPanel.Title = 'Data';
            app.ImportButton = uibutton(app.DataPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = 'import_24.png';
            app.ImportButton.Text = 'Import';
            app.ExportButton_3 = uibutton(app.DataPanel, 'push');
            app.ExportButton_3.Icon = 'export.png';
            app.ExportButton_3.Text = 'Export';
            app.DuplicateButton = uibutton(app.DataPanel, 'push');
            app.DuplicateButton.Icon = 'Duplicate_16.png';
            app.DuplicateButton.Text = 'Duplicate';
            app.RemoveButton_3 = uibutton(app.DataPanel, 'push');
            app.RemoveButton_3.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_3Pushed, true);
            app.RemoveButton_3.Icon = 'Delete_16.png';
            app.RemoveButton_3.Text = 'Remove';
            app.NewGroupButton = uibutton(app.DataPanel, 'push');
            app.NewGroupButton.Icon = 'plus-12.png';
            app.NewGroupButton.Text = 'New Group';
            app.OptionsPanel = uipanel(app.GeneralTab);
            app.OptionsPanel.Title = 'Options';
            app.SettingsButton = uibutton(app.OptionsPanel, 'push');
            app.SettingsButton.Icon = fullfile(pathToMLAPP, 'images', 'seetings_48.png');
            app.SettingsButton.Enable = 'off';
            app.SettingsButton.Text = 'Settings';
            app.AutomaticallyupdatepreviewCheckBox = uicheckbox(app.OptionsPanel);
            app.AutomaticallyupdatepreviewCheckBox.Enable = 'off';
            app.AutomaticallyupdatepreviewCheckBox.Text = 'Automatically update preview';
            app.AutomaticallyopenLAviewerCheckBox = uicheckbox(app.OptionsPanel);
            app.AutomaticallyopenLAviewerCheckBox.Enable = 'off';
            app.AutomaticallyopenLAviewerCheckBox.Text = 'Automatically open LA viewer';
            app.PeakAnalysisTab = uitab(app.TabGroup);
            app.PeakAnalysisTab.Title = 'Peak Analysis';
            app.ExtractPeaksPanel = uipanel(app.PeakAnalysisTab);
            app.ExtractPeaksPanel.Title = 'Extract Peaks';
            app.ExtractPeakTableButton = uibutton(app.ExtractPeaksPanel, 'push');
            app.ExtractPeakTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractPeakTableButtonPushed, true);
            app.ExtractPeakTableButton.Tooltip = {'Extract peak table and add to data container.'};
            app.ExtractPeakTableButton.Text = 'Extract Peak Table';
            app.PeakTablePreviewButton = uibutton(app.ExtractPeaksPanel, 'push');
            app.PeakTablePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PeakTablePreviewButtonPushed, true);
            app.PeakTablePreviewButton.Tooltip = {'Preview peaks.'};
            app.PeakTablePreviewButton.Text = 'P';
            app.MinimumProminenceEditFieldLabel = uilabel(app.ExtractPeaksPanel);
            app.MinimumProminenceEditFieldLabel.Text = 'Minimum Prominence';
            app.MinimumProminenceEditField = uieditfield(app.ExtractPeaksPanel, 'numeric');
            app.MinimumProminenceEditField.Limits = [0 1];
            app.MinimumProminenceEditField.Tooltip = {'Default value = 0.1'};
            app.MinimumProminenceEditField.Value = 0.1;
            app.PeakAnalysisTarget = uibuttongroup(app.ExtractPeaksPanel);
            app.FromSelectioninDataManagerButton = uiradiobutton(app.PeakAnalysisTarget);
            app.FromSelectioninDataManagerButton.Text = 'From Selection in Data Manager';
            app.FromSelectioninDataManagerButton.Value = true;
            app.FromSelectedDataItemButton = uiradiobutton(app.PeakAnalysisTarget);
            app.FromSelectedDataItemButton.Text = 'From Selected Data Item';
            app.PeakAnalysisNegativeCheckbox = uicheckbox(app.ExtractPeaksPanel);
            app.PeakAnalysisNegativeCheckbox.Tooltip = {'Also extract negative peaks (e.g. in PCA loadings)'};
            app.PeakAnalysisNegativeCheckbox.Text = 'Include negative peaks';
            app.MinimumHeightEditFieldLabel = uilabel(app.ExtractPeaksPanel);
            app.MinimumHeightEditFieldLabel.Text = 'Minimum Height';
            app.MinimumHeightEditField = uieditfield(app.ExtractPeaksPanel, 'numeric');
            app.MinimumHeightEditField.Limits = [0 1];
            app.MinimumHeightEditField.Tooltip = {'Default value = 0.1'};
            app.MinimumHeightEditField.Value = 0.1;
            app.SpectralAnalysisTab = uitab(app.TabGroup);
            app.SpectralAnalysisTab.Title = 'Spectral Analysis';
            app.AnalysisSubsetPanel = uipanel(app.SpectralAnalysisTab);
            app.AnalysisSubsetPanel.Title = 'Analysis Subset';
            app.SubsetNameEditFieldLabel = uilabel(app.AnalysisSubsetPanel);
            app.SubsetNameEditFieldLabel.Text = 'Subset Name';
            app.SubsetNameEditField = uieditfield(app.AnalysisSubsetPanel, 'text');
            app.AutoassignsamplesreplicatesButton = uibutton(app.AnalysisSubsetPanel, 'push');
            app.AutoassignsamplesreplicatesButton.ButtonPushedFcn = createCallbackFcn(app, @AutoassignsamplesreplicatesButtonPushed, true);
            app.AutoassignsamplesreplicatesButton.Text = 'Auto-assign samples / replicates';
            app.OpenDataTableButton = uibutton(app.AnalysisSubsetPanel, 'push');
            app.OpenDataTableButton.Text = 'Open Data Table ...';
            app.PlottingPanel_2 = uipanel(app.SpectralAnalysisTab);
            app.PlottingPanel_2.Title = 'Plotting';
            app.SpectralPlotButton = uibutton(app.PlottingPanel_2, 'push');
            app.SpectralPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SpectralPlotButtonPushed, true);
            app.SpectralPlotButton.Text = 'Spectral Plot...';
            app.PrincipalComponentAnalysisPanel = uipanel(app.SpectralAnalysisTab);
            app.PrincipalComponentAnalysisPanel.Title = 'Principal Component Analysis';
            app.PCAAnalyzeButton = uibutton(app.PrincipalComponentAnalysisPanel, 'push');
            app.PCAAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @PCAAnalyzeButtonPushed, true);
            app.PCAAnalyzeButton.Icon = fullfile(pathToMLAPP, 'images', 'New_24.png');
            app.PCAAnalyzeButton.Text = {'New'; 'PCA'};
            app.ButtonGroup = uibuttongroup(app.PrincipalComponentAnalysisPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PCAEntireSpectrumButton = uiradiobutton(app.ButtonGroup);
            app.PCAEntireSpectrumButton.Text = 'Entire Spectrum';
            app.PCAEntireSpectrumButton.Value = true;
            app.PCARangeButton = uiradiobutton(app.ButtonGroup);
            app.PCARangeButton.Text = 'Range:';
            app.cm1EditFieldLabel = uilabel(app.ButtonGroup);
            app.cm1EditFieldLabel.Text = 'cm-1';
            app.PCAEndRangeField = uieditfield(app.ButtonGroup, 'numeric');
            app.PCAEndRangeField.ValueChangedFcn = createCallbackFcn(app, @PCAEndRangeFieldValueChanged, true);
            app.PCAEndRangeField.Value = 1800;
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '-';
            app.PCAStartRangeField = uieditfield(app.ButtonGroup, 'numeric');
            app.PCAStartRangeField.ValueChangedFcn = createCallbackFcn(app, @PCAStartRangeFieldValueChanged, true);
            app.PCAStartRangeField.Value = 600;
            app.AlgorithmLabel = uilabel(app.PrincipalComponentAnalysisPanel);
            app.AlgorithmLabel.Text = 'Algorithm:';
            app.AlgorithmDropDown = uidropdown(app.PrincipalComponentAnalysisPanel);
            app.AlgorithmDropDown.Items = {'svd', 'svd-nsc', 'eig', 'als', 'nipals'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'svd';
            app.pca_options_button = uibutton(app.PrincipalComponentAnalysisPanel, 'push');
            app.pca_options_button.ButtonPushedFcn = createCallbackFcn(app, @pca_options_buttonPushed, true);
            app.pca_options_button.Icon = fullfile(pathToMLAPP, 'images', 'seetings_48.png');
            app.pca_options_button.Text = 'Options...';
            app.tSNEPanel = uipanel(app.SpectralAnalysisTab);
            app.tSNEPanel.Title = 't-SNE';
            app.NewTSNE = uibutton(app.tSNEPanel, 'push');
            app.NewTSNE.ButtonPushedFcn = createCallbackFcn(app, @NewTSNEButtonPushed, true);
            app.NewTSNE.Icon = fullfile(pathToMLAPP, 'images', 'New_24.png');
            app.NewTSNE.Text = {'New'; 't-SNE'};
            app.tSNEOptions = uibuttongroup(app.tSNEPanel);
            app.tsneEntireSpectrumButton = uiradiobutton(app.tSNEOptions);
            app.tsneEntireSpectrumButton.Enable = 'off';
            app.tsneEntireSpectrumButton.Text = 'Entire Spectrum';
            app.tsneEntireSpectrumButton.Value = true;
            app.tSNERangeButton = uiradiobutton(app.tSNEOptions);
            app.tSNERangeButton.Enable = 'off';
            app.tSNERangeButton.Text = 'Range:';
            app.cm1EditFieldLabel_2 = uilabel(app.tSNEOptions);
            app.cm1EditFieldLabel_2.Enable = 'off';
            app.cm1EditFieldLabel_2.Text = 'cm-1';
            app.tsneEndRangeField = uieditfield(app.tSNEOptions, 'numeric');
            app.tsneEndRangeField.Enable = 'off';
            app.Label_2 = uilabel(app.tSNEOptions);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '-';
            app.tsneStartRangeField = uieditfield(app.tSNEOptions, 'numeric');
            app.tsneStartRangeField.Enable = 'off';
            app.AlgorithmDropDown_2Label = uilabel(app.tSNEPanel);
            app.AlgorithmDropDown_2Label.Text = 'Algorithm';
            app.tsneAlgorithmDropDown = uidropdown(app.tSNEPanel);
            app.tsneAlgorithmDropDown.Items = {'barneshut', 'exact'};
            app.tsneAlgorithmDropDown.Value = 'barneshut';
            app.DistanceMethodDropDownLabel = uilabel(app.tSNEPanel);
            app.DistanceMethodDropDownLabel.Text = 'Distance Method:';
            app.DistanceMethodDropDown = uidropdown(app.tSNEPanel);
            app.DistanceMethodDropDown.Items = {'euclidean', 'seuclidean', 'cityblock', 'chebychev', 'minkowski', 'mahalanobis', 'cosine', 'correlation', 'spearman', 'hamming', 'jaccard'};
            app.DistanceMethodDropDown.Value = 'euclidean';
            app.AreaScanTab = uitab(app.TabGroup);
            app.AreaScanTab.Title = 'Area Scan';
            app.OverviewPanel_2 = uipanel(app.AreaScanTab);
            app.OverviewPanel_2.Title = 'Overview';
            app.AreaScanOverview = uitextarea(app.OverviewPanel_2);
            app.AreaScanOverview.Editable = 'off';
            app.FiltersPanel = uipanel(app.AreaScanTab);
            app.FiltersPanel.Title = 'Filters';
            app.ManageFiltersButton = uibutton(app.FiltersPanel, 'push');
            app.ManageFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ManageFiltersButtonPushed, true);
            app.ManageFiltersButton.Icon = 'filter_16.png';
            app.ManageFiltersButton.Text = 'Manage Filters';
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.DAnalysisPanel = uipanel(app.PCATab);
            app.DAnalysisPanel.Title = '1-D Analysis';
            app.LoadingsButtonY_Preview_2 = uibutton(app.DAnalysisPanel, 'push');
            app.LoadingsButtonY_Preview_2.ButtonPushedFcn = createCallbackFcn(app, @LoadingsButtonY_Preview_2Pushed, true);
            app.LoadingsButtonY_Preview_2.Tooltip = {'Preview Y Loadings'};
            app.LoadingsButtonY_Preview_2.Text = 'P';
            app.LoadingsButtonY = uibutton(app.DAnalysisPanel, 'push');
            app.LoadingsButtonY.ButtonPushedFcn = createCallbackFcn(app, @LoadingsButtonYPushed, true);
            app.LoadingsButtonY.Text = 'Loadings';
            app.LoadingsButtonX_Preview = uibutton(app.DAnalysisPanel, 'push');
            app.LoadingsButtonX_Preview.ButtonPushedFcn = createCallbackFcn(app, @LoadingsButtonX_PreviewPushed, true);
            app.LoadingsButtonX_Preview.Tooltip = {'Preview X Loadings'};
            app.LoadingsButtonX_Preview.Text = 'P';
            app.LoadingsButtonX = uibutton(app.DAnalysisPanel, 'push');
            app.LoadingsButtonX.ButtonPushedFcn = createCallbackFcn(app, @LoadingsButtonXPushed, true);
            app.LoadingsButtonX.Text = 'Loadings';
            app.ScoresButtonX = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonX.Text = 'Scores';
            app.ScoresButtonY = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonY.Text = 'Scores';
            app.PCXSpinnerLabel = uilabel(app.DAnalysisPanel);
            app.PCXSpinnerLabel.Text = 'PC X';
            app.PCXSpinner = uispinner(app.DAnalysisPanel);
            app.PCXSpinner.Limits = [1 Inf];
            app.PCXSpinner.ValueChangedFcn = createCallbackFcn(app, @PCXSpinnerValueChanged, true);
            app.PCXSpinner.Value = 1;
            app.PCYSpinnerLabel = uilabel(app.DAnalysisPanel);
            app.PCYSpinnerLabel.Text = 'PC Y';
            app.PCYSpinner = uispinner(app.DAnalysisPanel);
            app.PCYSpinner.Limits = [1 Inf];
            app.PCYSpinner.ValueChangedFcn = createCallbackFcn(app, @PCYSpinnerValueChanged, true);
            app.PCYSpinner.Value = 2;
            app.ScoresButtonX_Preview = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonX_Preview.Tooltip = {'Preview X Loadings'};
            app.ScoresButtonX_Preview.Text = 'P';
            app.ScoresButtonY_Preview = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonY_Preview.Tooltip = {'Preview X Loadings'};
            app.ScoresButtonY_Preview.Text = 'P';
            app.AverageScoresPerSample = uicheckbox(app.DAnalysisPanel);
            app.AverageScoresPerSample.Text = 'Average scores by sample / replicate';
            app.ScoresButtonX_Preview_3 = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonX_Preview_3.Tooltip = {'Preview X Loadings'};
            app.ScoresButtonX_Preview_3.Text = 'E';
            app.ScoresButtonX_Preview_4 = uibutton(app.DAnalysisPanel, 'push');
            app.ScoresButtonX_Preview_4.Tooltip = {'Preview X Loadings'};
            app.ScoresButtonX_Preview_4.Text = 'E';
            app.MultipleScoresButton = uibutton(app.DAnalysisPanel, 'push');
            app.MultipleScoresButton.Text = 'Multiple Scores';
            app.DAnalysisPanel_2 = uipanel(app.PCATab);
            app.DAnalysisPanel_2.Title = '2-D Analysis';
            app.Scores2DScatterPlotButton = uibutton(app.DAnalysisPanel_2, 'push');
            app.Scores2DScatterPlotButton.ButtonPushedFcn = createCallbackFcn(app, @Scores2DScatterPlotButtonPushed, true);
            app.Scores2DScatterPlotButton.Text = 'Scores 2D Scatter Plot';
            app.ConfidenceIntervalButtonGroup = uibuttongroup(app.DAnalysisPanel_2);
            app.ConfidenceIntervalButtonGroup.Title = 'Confidence Interval:';
            app.RadioConfInt950 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt950.Enable = 'off';
            app.RadioConfInt950.Text = '95%';
            app.RadioConfInt950.Value = true;
            app.RadioConfInt990 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt990.Enable = 'off';
            app.RadioConfInt990.Text = '99%';
            app.RadioConfInt999 = uiradiobutton(app.ConfidenceIntervalButtonGroup);
            app.RadioConfInt999.Enable = 'off';
            app.RadioConfInt999.Text = '99.9%';
            app.OptionsButton = uibutton(app.DAnalysisPanel_2, 'push');
            app.OptionsButton.Text = 'Options...';
            app.PCAErrorEllipseCheckBox = uicheckbox(app.DAnalysisPanel_2);
            app.PCAErrorEllipseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PCAErrorEllipseCheckBoxValueChanged, true);
            app.PCAErrorEllipseCheckBox.Text = 'Show Confidence Ellipses';
            app.OverviewPanel = uipanel(app.PCATab);
            app.OverviewPanel.Title = 'Overview';
            app.PCADescription = uitextarea(app.OverviewPanel);
            app.PCADescription.Editable = 'off';
            app.RecalculateButton = uibutton(app.OverviewPanel, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateButtonPushed, true);
            app.RecalculateButton.Tooltip = {'Recalculate PCA from linked analysis.'};
            app.RecalculateButton.Text = 'Recalculate';
            app.RemovePCA_Button = uibutton(app.OverviewPanel, 'push');
            app.RemovePCA_Button.Icon = fullfile(pathToMLAPP, 'images', 'Delete_16.png');
            app.RemovePCA_Button.Tooltip = {'Recalculate PCA from linked analysis.'};
            app.RemovePCA_Button.Text = 'Remove';
            app.DataStructurePanel = uipanel(app.PCATab);
            app.DataStructurePanel.Title = 'Data Structure';
            app.PCADataStructureTree = uitree(app.DataStructurePanel);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DataMgrTree = uitree(app.GridLayout2);
            app.DataMgrTree.Multiselect = 'on';
            app.DataMgrTree.SelectionChangedFcn = createCallbackFcn(app, @DataMgrTreeSelectionChanged, true);
            app.DataMgrTree.NodeTextChangedFcn = createCallbackFcn(app, @DataMgrTreeNodeTextChanged, true);
            app.DataMgrTree.Editable = 'on';
            app.DataManagerLabel = uilabel(app.GridLayout2);
            app.DataManagerLabel.Text = 'Data Manager';
            app.Panel = uipanel(app.GridLayout2);
            app.btnExportSmall = uibutton(app.Panel, 'push');
            app.btnExportSmall.Icon = 'export_16.png';
            app.btnExportSmall.Text = '';
            app.btnDuplicateSmall = uibutton(app.Panel, 'push');
            app.btnDuplicateSmall.ButtonPushedFcn = createCallbackFcn(app, @btnDuplicateSmallButtonPushed, true);
            app.btnDuplicateSmall.Icon = 'Duplicate_16.png';
            app.btnDuplicateSmall.Enable = 'off';
            app.btnDuplicateSmall.Text = '';
            app.btnAddGroupSmall = uibutton(app.Panel, 'push');
            app.btnAddGroupSmall.ButtonPushedFcn = createCallbackFcn(app, @btnAddGroupSmallPushed, true);
            app.btnAddGroupSmall.Icon = 'plus-12.png';
            app.btnAddGroupSmall.Text = '';
            app.btnImportSmall = uibutton(app.Panel, 'push');
            app.btnImportSmall.ButtonPushedFcn = createCallbackFcn(app, @btnImportSmallPushed, true);
            app.btnImportSmall.Icon = 'import_16.png';
            app.btnImportSmall.Text = '';
            app.btnDeleteSmall = uibutton(app.Panel, 'push');
            app.btnDeleteSmall.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteSmallButtonPushed, true);
            app.btnDeleteSmall.Icon = 'Delete_16.png';
            app.btnDeleteSmall.Text = '';
            app.btnRefresh = uibutton(app.Panel, 'push');
            app.btnRefresh.ButtonPushedFcn = createCallbackFcn(app, @btnRefreshButtonPushed, true);
            app.btnRefresh.Text = 'Update';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.SubsetTree = uitree(app.GridLayout3);
            app.SubsetTree.SelectionChangedFcn = createCallbackFcn(app, @SubsetTreeSelectionChanged, true);
            app.SubsetTree.NodeTextChangedFcn = createCallbackFcn(app, @SubsetTreeNodeTextChanged, true);
            app.SubsetTree.Editable = 'on';
            app.AnalysisMgrTree = uitree(app.GridLayout3);
            app.AnalysisMgrTree.NodeTextChangedFcn = createCallbackFcn(app, @AnalysisMgrTreeNodeTextChanged, true);
            app.AnalysisLabel = uilabel(app.GridLayout2);
            app.AnalysisLabel.Text = 'Analysis';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.UIPreviewAxes = uiaxes(app.GridLayout4);
            app.DataItemsTree = uitree(app.GridLayout4);
            app.DataContainerNode = uitreenode(app.DataItemsTree);
            app.DataContainerNode.Text = 'DataContainer';
            app.DataItemNode = uitreenode(app.DataContainerNode);
            app.DataItemNode.Text = 'DataItem';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.RemoveItemButton = uibutton(app.Panel_3, 'push');
            app.RemoveItemButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveItemButtonPushed, true);
            app.RemoveItemButton.Icon = fullfile(pathToMLAPP, 'images', 'Delete_16.png');
            app.RemoveItemButton.Text = '';
            app.DumpItemtoWorkspaceButton = uibutton(app.Panel_3, 'push');
            app.DumpItemtoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @DumpItemtoWorkspaceButtonPushed, true);
            app.DumpItemtoWorkspaceButton.Text = 'Dump Item to Workspace';
            app.ExportButton_2 = uibutton(app.Panel_3, 'push');
            app.ExportButton_2.Text = 'Export...';
            app.SetDefaultButton = uibutton(app.Panel_3, 'push');
            app.SetDefaultButton.Enable = 'off';
            app.SetDefaultButton.Text = 'Set Default';
            app.PlotItemButton = uibutton(app.Panel_3, 'push');
            app.PlotItemButton.ButtonPushedFcn = createCallbackFcn(app, @PlotItemButtonPushed, true);
            app.PlotItemButton.Text = 'Plot';
            app.AnalysisPanel = uipanel(app.GridLayout2);
            app.AnalysisMoveUpButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalysisMoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisMoveUpButtonPushed, true);
            app.AnalysisMoveUpButton.Text = '▲';
            app.AnalysisMoveDownButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalysisMoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisMoveDownButtonPushed, true);
            app.AnalysisMoveDownButton.Text = '▼';
            app.AnalysisLinkRemoveButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalysisLinkRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisLinkRemoveButtonPushed, true);
            app.AnalysisLinkRemoveButton.Icon = 'Delete_16.png';
            app.AnalysisLinkRemoveButton.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.DataPreviewLabel = uilabel(app.GridLayout5);
            app.DataPreviewLabel.Text = 'Data Preview:';
            app.DataContainerNameLabel = uilabel(app.GridLayout5);
            app.DataContainerNameLabel.Text = '//';
            app.contxtSaveAs = uicontextmenu(app.DVRamanToolUIFigure);
            app.SaveMenu_2 = uimenu(app.contxtSaveAs);
            app.SaveMenu_2.Text = 'Save';
            app.SaveAsMenu_2 = uimenu(app.contxtSaveAs);
            app.SaveAsMenu_2.Text = 'Save As ...';
            % Assign app.contxtSaveAs
            app.btnSaveMore.ContextMenu = app.contxtSaveAs;
            app.contxtDataMgrTreeNode = uicontextmenu(app.DVRamanToolUIFigure);
            app.contxtDataMgrTreeNode.ContextMenuOpeningFcn = createCallbackFcn(app, @contxtDataMgrTreeNodeContextMenuOpening, true);
            app.AddtoNewAnalysisMenu = uimenu(app.contxtDataMgrTreeNode);
            app.AddtoNewAnalysisMenu.Text = 'Add to New Analysis';
            app.AddtoMenu = uimenu(app.contxtDataMgrTreeNode);
            app.AddtoMenu.Text = 'Add to ...';
            app.MovetoMenu = uimenu(app.contxtDataMgrTreeNode);
            app.MovetoMenu.Text = 'Move to ...';
            app.DumptoWorkspaceMenu = uimenu(app.contxtDataMgrTreeNode);
            app.DumptoWorkspaceMenu.Text = 'Dump to Workspace';
            app.emptyMenuPlaceholder = uimenu(app.contxtDataMgrTreeNode);
            app.emptyMenuPlaceholder.Text = 'Delete';
            app.contxtSubsetTreeNode = uicontextmenu(app.DVRamanToolUIFigure);
            app.contxtSubsetTreeNode.ContextMenuOpeningFcn = createCallbackFcn(app, @contxtSubsetTreeNodeContextMenuOpening, true);
            app.AssigntoNewGroupMenu = uimenu(app.contxtSubsetTreeNode);
            app.AssigntoNewGroupMenu.Text = 'Assign to New Group';
            app.AssigntoMenu = uimenu(app.contxtSubsetTreeNode);
            app.AssigntoMenu.Text = 'Assign to ...';
            app.RemoveMenu_2 = uimenu(app.contxtSubsetTreeNode);
            app.RemoveMenu_2.Text = 'Remove';
            % Show the figure after all components are created
            app.DVRamanToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ramatguiapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DVRamanToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DVRamanToolUIFigure)
        end
    end
end